classdef storage_manager < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout_Main                matlab.ui.container.GridLayout
        GridLayout_ManagerItemsContainer  matlab.ui.container.GridLayout
        GridLayout_ManagerItemsTitle   matlab.ui.container.GridLayout
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        GridLayout_PercentageOverview  matlab.ui.container.GridLayout
        GridLayout_HardDriveUsed_ProgressBar  matlab.ui.container.GridLayout
        Label_HardDriveLeftPart        matlab.ui.control.Label
        Label_HardDriveUsedPart        matlab.ui.control.Label
        GridLayout_MemoryUsed_ProgressBar  matlab.ui.container.GridLayout
        Label_MemoryLeftPart           matlab.ui.control.Label
        Label_MemoryUsedPart           matlab.ui.control.Label
        Label_HardDriveUsedPercentage  matlab.ui.control.Label
        Label_MemoryUsedPercentage     matlab.ui.control.Label
        Label_HardDriveUsedTip         matlab.ui.control.Label
        Label_MemoryUsedTip            matlab.ui.control.Label
        Panel_DonutOverview            matlab.ui.container.Panel
    end

    properties (Constant)
        VER = "1.0.0"
    end
    
    properties (Access = private, Hidden)
        caller  (1,1)           % the register object

        langmap;        % mapping for language
    end
    
    methods (Access = ?Register)
        function SetParentHandle(comp, p)
            arguments
                comp 
                p       (1,1)   Register
            end

           comp.caller = p;
        end

        function RefreshPanelStyle(comp)
            style = comp.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    
                case "DARK"
                    
                otherwise
            end
        end

        function RefreshPanelLanguage(comp)
            [~, comp.langmap] = comp.caller.GetLangsInfo();

            
        end
    end


    methods (Access = ?regohm)
        
        function UpdateView(comp)
            % regohm invokes this component 
        end
    end
    

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            % 使用此函数更新底层组件
            
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 260 351];

            % Create GridLayout_Main
            comp.GridLayout_Main = uigridlayout(comp);
            comp.GridLayout_Main.ColumnWidth = {'1x'};
            comp.GridLayout_Main.RowHeight = {70, 120, 25, '1x'};
            comp.GridLayout_Main.RowSpacing = 5;
            comp.GridLayout_Main.Padding = [0 0 0 0];

            % Create Panel_DonutOverview
            comp.Panel_DonutOverview = uipanel(comp.GridLayout_Main);
            comp.Panel_DonutOverview.Layout.Row = 2;
            comp.Panel_DonutOverview.Layout.Column = 1;

            % Create GridLayout_PercentageOverview
            comp.GridLayout_PercentageOverview = uigridlayout(comp.GridLayout_Main);
            comp.GridLayout_PercentageOverview.ColumnWidth = {60, '1x', 55};
            comp.GridLayout_PercentageOverview.ColumnSpacing = 5;
            comp.GridLayout_PercentageOverview.Padding = [5 5 5 5];
            comp.GridLayout_PercentageOverview.Layout.Row = 1;
            comp.GridLayout_PercentageOverview.Layout.Column = 1;

            % Create Label_MemoryUsedTip
            comp.Label_MemoryUsedTip = uilabel(comp.GridLayout_PercentageOverview);
            comp.Label_MemoryUsedTip.HorizontalAlignment = 'right';
            comp.Label_MemoryUsedTip.FontSize = 14;
            comp.Label_MemoryUsedTip.Layout.Row = 1;
            comp.Label_MemoryUsedTip.Layout.Column = 1;
            comp.Label_MemoryUsedTip.Text = '内存使用';

            % Create Label_HardDriveUsedTip
            comp.Label_HardDriveUsedTip = uilabel(comp.GridLayout_PercentageOverview);
            comp.Label_HardDriveUsedTip.HorizontalAlignment = 'right';
            comp.Label_HardDriveUsedTip.FontSize = 14;
            comp.Label_HardDriveUsedTip.Layout.Row = 2;
            comp.Label_HardDriveUsedTip.Layout.Column = 1;
            comp.Label_HardDriveUsedTip.Text = '硬盘使用';

            % Create Label_MemoryUsedPercentage
            comp.Label_MemoryUsedPercentage = uilabel(comp.GridLayout_PercentageOverview);
            comp.Label_MemoryUsedPercentage.HorizontalAlignment = 'center';
            comp.Label_MemoryUsedPercentage.Layout.Row = 1;
            comp.Label_MemoryUsedPercentage.Layout.Column = 3;
            comp.Label_MemoryUsedPercentage.Text = '  19.2 GB';

            % Create Label_HardDriveUsedPercentage
            comp.Label_HardDriveUsedPercentage = uilabel(comp.GridLayout_PercentageOverview);
            comp.Label_HardDriveUsedPercentage.HorizontalAlignment = 'center';
            comp.Label_HardDriveUsedPercentage.Layout.Row = 2;
            comp.Label_HardDriveUsedPercentage.Layout.Column = 3;
            comp.Label_HardDriveUsedPercentage.Text = '115.2 GB';

            % Create GridLayout_MemoryUsed_ProgressBar
            comp.GridLayout_MemoryUsed_ProgressBar = uigridlayout(comp.GridLayout_PercentageOverview);
            comp.GridLayout_MemoryUsed_ProgressBar.ColumnWidth = {'3x', '7x'};
            comp.GridLayout_MemoryUsed_ProgressBar.RowHeight = {'1x'};
            comp.GridLayout_MemoryUsed_ProgressBar.ColumnSpacing = 0;
            comp.GridLayout_MemoryUsed_ProgressBar.Padding = [0 5 0 5];
            comp.GridLayout_MemoryUsed_ProgressBar.Layout.Row = 1;
            comp.GridLayout_MemoryUsed_ProgressBar.Layout.Column = 2;

            % Create Label_MemoryUsedPart
            comp.Label_MemoryUsedPart = uilabel(comp.GridLayout_MemoryUsed_ProgressBar);
            comp.Label_MemoryUsedPart.BackgroundColor = [0.302 0.7451 0.9333];
            comp.Label_MemoryUsedPart.HorizontalAlignment = 'center';
            comp.Label_MemoryUsedPart.FontWeight = 'bold';
            comp.Label_MemoryUsedPart.Layout.Row = 1;
            comp.Label_MemoryUsedPart.Layout.Column = 1;
            comp.Label_MemoryUsedPart.Text = '30%';

            % Create Label_MemoryLeftPart
            comp.Label_MemoryLeftPart = uilabel(comp.GridLayout_MemoryUsed_ProgressBar);
            comp.Label_MemoryLeftPart.BackgroundColor = [0.902 0.902 0.902];
            comp.Label_MemoryLeftPart.Layout.Row = 1;
            comp.Label_MemoryLeftPart.Layout.Column = 2;
            comp.Label_MemoryLeftPart.Text = '';

            % Create GridLayout_HardDriveUsed_ProgressBar
            comp.GridLayout_HardDriveUsed_ProgressBar = uigridlayout(comp.GridLayout_PercentageOverview);
            comp.GridLayout_HardDriveUsed_ProgressBar.ColumnWidth = {'9x', '1x'};
            comp.GridLayout_HardDriveUsed_ProgressBar.RowHeight = {'1x'};
            comp.GridLayout_HardDriveUsed_ProgressBar.ColumnSpacing = 0;
            comp.GridLayout_HardDriveUsed_ProgressBar.Padding = [0 5 0 5];
            comp.GridLayout_HardDriveUsed_ProgressBar.Layout.Row = 2;
            comp.GridLayout_HardDriveUsed_ProgressBar.Layout.Column = 2;

            % Create Label_HardDriveUsedPart
            comp.Label_HardDriveUsedPart = uilabel(comp.GridLayout_HardDriveUsed_ProgressBar);
            comp.Label_HardDriveUsedPart.BackgroundColor = [1 0.349 0.349];
            comp.Label_HardDriveUsedPart.HorizontalAlignment = 'center';
            comp.Label_HardDriveUsedPart.FontWeight = 'bold';
            comp.Label_HardDriveUsedPart.Layout.Row = 1;
            comp.Label_HardDriveUsedPart.Layout.Column = 1;
            comp.Label_HardDriveUsedPart.Text = '90%';

            % Create Label_HardDriveLeftPart
            comp.Label_HardDriveLeftPart = uilabel(comp.GridLayout_HardDriveUsed_ProgressBar);
            comp.Label_HardDriveLeftPart.BackgroundColor = [0.902 0.902 0.902];
            comp.Label_HardDriveLeftPart.Layout.Row = 1;
            comp.Label_HardDriveLeftPart.Layout.Column = 2;
            comp.Label_HardDriveLeftPart.Text = '';

            % Create GridLayout_ManagerItemsTitle
            comp.GridLayout_ManagerItemsTitle = uigridlayout(comp.GridLayout_Main);
            comp.GridLayout_ManagerItemsTitle.ColumnWidth = {40, '1x', 60, 20};
            comp.GridLayout_ManagerItemsTitle.RowHeight = {'1x'};
            comp.GridLayout_ManagerItemsTitle.ColumnSpacing = 5;
            comp.GridLayout_ManagerItemsTitle.Padding = [0 0 0 0];
            comp.GridLayout_ManagerItemsTitle.Layout.Row = 3;
            comp.GridLayout_ManagerItemsTitle.Layout.Column = 1;

            % Create Label
            comp.Label = uilabel(comp.GridLayout_ManagerItemsTitle);
            comp.Label.HorizontalAlignment = 'center';
            comp.Label.FontSize = 14;
            comp.Label.FontWeight = 'bold';
            comp.Label.Layout.Row = 1;
            comp.Label.Layout.Column = 1;
            comp.Label.Text = '标签';

            % Create Label_2
            comp.Label_2 = uilabel(comp.GridLayout_ManagerItemsTitle);
            comp.Label_2.HorizontalAlignment = 'center';
            comp.Label_2.FontSize = 14;
            comp.Label_2.FontWeight = 'bold';
            comp.Label_2.Layout.Row = 1;
            comp.Label_2.Layout.Column = 2;
            comp.Label_2.Text = '使用率';

            % Create Label_3
            comp.Label_3 = uilabel(comp.GridLayout_ManagerItemsTitle);
            comp.Label_3.HorizontalAlignment = 'center';
            comp.Label_3.FontSize = 14;
            comp.Label_3.FontWeight = 'bold';
            comp.Label_3.Layout.Row = 1;
            comp.Label_3.Layout.Column = 3;
            comp.Label_3.Text = '存储';

            % Create GridLayout_ManagerItemsContainer
            comp.GridLayout_ManagerItemsContainer = uigridlayout(comp.GridLayout_Main);
            comp.GridLayout_ManagerItemsContainer.ColumnWidth = {'1x'};
            comp.GridLayout_ManagerItemsContainer.RowHeight = {25, 25, 25};
            comp.GridLayout_ManagerItemsContainer.Padding = [0 0 0 0];
            comp.GridLayout_ManagerItemsContainer.Layout.Row = 4;
            comp.GridLayout_ManagerItemsContainer.Layout.Column = 1;
        end
    end
end