classdef InfoPage < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout2                  matlab.ui.container.GridLayout
        Panel_UserBehaviours         matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        UITable_Info_User            matlab.ui.control.Table
        Panel_EnvironmentVariables   matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        EditField_Version_Collector  matlab.ui.control.EditField
        EditField_5Label             matlab.ui.control.Label
        EditField_Version_MainApp    matlab.ui.control.EditField
        EditField_4Label             matlab.ui.control.Label
        EditField_Version_Python     matlab.ui.control.EditField
        PythonEditFieldLabel         matlab.ui.control.Label
        EditField_Version_Java       matlab.ui.control.EditField
        JavaEditFieldLabel           matlab.ui.control.Label
        ListBox_InstalledToolbox     matlab.ui.control.ListBox
        ListBoxLabel                 matlab.ui.control.Label
        EditField_Version_MATLAB     matlab.ui.control.EditField
        MATLABEditFieldLabel         matlab.ui.control.Label
        EditField_Info_Memory        matlab.ui.control.EditField
        EditField_3Label             matlab.ui.control.Label
        EditField_Info_GPU           matlab.ui.control.EditField
        GPUEditFieldLabel            matlab.ui.control.Label
        EditField_Info_CPU           matlab.ui.control.EditField
        CPUEditFieldLabel            matlab.ui.control.Label
        EditField_CreationTime       matlab.ui.control.EditField
        EditField_2Label             matlab.ui.control.Label
        EditField_HostName           matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
    end

    properties (AbortSet, Access = public, GetObservable, SetObservable)
        IsEnable (1,1) logical {mustBeNumericOrLogical(IsEnable)} = false; % Control if components interactions are valid
        IsVisible (1,1) logical {mustBeNumericOrLogical(IsVisible)} = true; % Control if components are visible
    end
    
    properties (AbortSet, Access = public, GetObservable, SetObservable)
        EnvInfo     (1,1)   struct  = struct()
        UserInfo    (:,4)   table   = table('Size',[0,4], 'VariableTypes', ...
                                    {'datetime','string','string','string'}, ...
                                    'VariableNames',{'Time','Name','Callback','Exception'})
    end

    methods (Access = private)

        function update_environment_info_panel(comp)
            % set global properties
            comp.Panel_EnvironmentVariables.Enable = comp.IsEnable;
            comp.Panel_EnvironmentVariables.Visible = comp.IsVisible;

            % set local variables
            if comp.IsEnable == true
                comp.EditField_HostName.Value = comp.EnvInfo.HostName;
                comp.EditField_CreationTime.Value = comp.EnvInfo.CreationTime;
                comp.EditField_Info_CPU.Value = comp.EnvInfo.CPUInfo;
                comp.EditField_Info_GPU.Value = comp.EnvInfo.GPUInfo;
                comp.EditField_Info_Memory.Value = comp.EnvInfo.MemoryInfo;
                comp.EditField_Version_MATLAB.Value = comp.EnvInfo.MATLABVersion;
                comp.ListBox_InstalledToolbox.Items = comp.EnvInfo.ToolboxInstalled;
                comp.EditField_Version_Java.Value = comp.EnvInfo.JavaVersion;
                comp.EditField_Version_Python.Value = comp.EnvInfo.PythonVersion;
                comp.EditField_Version_MainApp.Value = comp.EnvInfo.RegisterVersion;
                comp.EditField_Version_Collector.Value = comp.EnvInfo.InformationCollectorVersion;
            end
        end

        function update_user_info_panel(comp)
            % set global properties
            comp.Panel_UserBehaviours.Enable = comp.IsEnable;
            comp.Panel_UserBehaviours.Visible = comp.IsVisible;

            % set local variables
            if comp.IsEnable == true
                comp.UITable_Info_User.Data = comp.UserInfo;
                comp.UITable_Info_User.ColumnName = ["时间", "子程序", "回调", "异常"];
            end
        end
    end

    methods (Access = protected)
        
        % 公共属性值发生更改时执行的代码
        function update(comp)
            update_environment_info_panel(comp);

            update_user_info_panel(comp);
        end

        % 创建底层组件
        function setup(comp)

            comp.Position = [1 1 900 540];
            comp.BackgroundColor = [0.94 0.94 0.94];

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp);
            comp.GridLayout2.ColumnWidth = {'1x', 525};
            comp.GridLayout2.RowHeight = {'1x'};
            comp.GridLayout2.ColumnSpacing = 5;
            comp.GridLayout2.Padding = [5 5 5 5];

            % Create Panel_EnvironmentVariables
            comp.Panel_EnvironmentVariables = uipanel(comp.GridLayout2);
            comp.Panel_EnvironmentVariables.TitlePosition = 'centertop';
            comp.Panel_EnvironmentVariables.Title = '环境变量';
            comp.Panel_EnvironmentVariables.Layout.Row = 1;
            comp.Panel_EnvironmentVariables.Layout.Column = 1;
            comp.Panel_EnvironmentVariables.FontSize = 14;

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp.Panel_EnvironmentVariables);
            comp.GridLayout.ColumnWidth = {90, '1x'};
            comp.GridLayout.RowHeight = {25, 25, 25, 25, 25, 25, 120, 25, 25, 25, 25, '1x'};
            comp.GridLayout.Scrollable = 'on';

            % Create EditFieldLabel
            comp.EditFieldLabel = uilabel(comp.GridLayout);
            comp.EditFieldLabel.HorizontalAlignment = 'right';
            comp.EditFieldLabel.FontSize = 14;
            comp.EditFieldLabel.Layout.Row = 1;
            comp.EditFieldLabel.Layout.Column = 1;
            comp.EditFieldLabel.Text = '主机名';

            % Create EditField_HostName
            comp.EditField_HostName = uieditfield(comp.GridLayout, 'text');
            comp.EditField_HostName.FontSize = 14;
            comp.EditField_HostName.Layout.Row = 1;
            comp.EditField_HostName.Layout.Column = 2;

            % Create EditField_2Label
            comp.EditField_2Label = uilabel(comp.GridLayout);
            comp.EditField_2Label.HorizontalAlignment = 'right';
            comp.EditField_2Label.FontSize = 14;
            comp.EditField_2Label.Layout.Row = 2;
            comp.EditField_2Label.Layout.Column = 1;
            comp.EditField_2Label.Text = '创建时间';

            % Create EditField_CreationTime
            comp.EditField_CreationTime = uieditfield(comp.GridLayout, 'text');
            comp.EditField_CreationTime.FontSize = 14;
            comp.EditField_CreationTime.Layout.Row = 2;
            comp.EditField_CreationTime.Layout.Column = 2;

            % Create CPUEditFieldLabel
            comp.CPUEditFieldLabel = uilabel(comp.GridLayout);
            comp.CPUEditFieldLabel.HorizontalAlignment = 'right';
            comp.CPUEditFieldLabel.FontSize = 14;
            comp.CPUEditFieldLabel.Layout.Row = 3;
            comp.CPUEditFieldLabel.Layout.Column = 1;
            comp.CPUEditFieldLabel.Text = 'CPU 信息';

            % Create EditField_Info_CPU
            comp.EditField_Info_CPU = uieditfield(comp.GridLayout, 'text');
            comp.EditField_Info_CPU.FontSize = 14;
            comp.EditField_Info_CPU.Layout.Row = 3;
            comp.EditField_Info_CPU.Layout.Column = 2;

            % Create GPUEditFieldLabel
            comp.GPUEditFieldLabel = uilabel(comp.GridLayout);
            comp.GPUEditFieldLabel.HorizontalAlignment = 'right';
            comp.GPUEditFieldLabel.FontSize = 14;
            comp.GPUEditFieldLabel.Layout.Row = 4;
            comp.GPUEditFieldLabel.Layout.Column = 1;
            comp.GPUEditFieldLabel.Text = 'GPU 信息';

            % Create EditField_Info_GPU
            comp.EditField_Info_GPU = uieditfield(comp.GridLayout, 'text');
            comp.EditField_Info_GPU.FontSize = 14;
            comp.EditField_Info_GPU.Layout.Row = 4;
            comp.EditField_Info_GPU.Layout.Column = 2;

            % Create EditField_3Label
            comp.EditField_3Label = uilabel(comp.GridLayout);
            comp.EditField_3Label.HorizontalAlignment = 'right';
            comp.EditField_3Label.FontSize = 14;
            comp.EditField_3Label.Layout.Row = 5;
            comp.EditField_3Label.Layout.Column = 1;
            comp.EditField_3Label.Text = '内存信息';

            % Create EditField_Info_Memory
            comp.EditField_Info_Memory = uieditfield(comp.GridLayout, 'text');
            comp.EditField_Info_Memory.FontSize = 14;
            comp.EditField_Info_Memory.Layout.Row = 5;
            comp.EditField_Info_Memory.Layout.Column = 2;

            % Create MATLABEditFieldLabel
            comp.MATLABEditFieldLabel = uilabel(comp.GridLayout);
            comp.MATLABEditFieldLabel.HorizontalAlignment = 'right';
            comp.MATLABEditFieldLabel.FontSize = 14;
            comp.MATLABEditFieldLabel.Layout.Row = 6;
            comp.MATLABEditFieldLabel.Layout.Column = 1;
            comp.MATLABEditFieldLabel.Text = 'MATLAB 版本';

            % Create EditField_Version_MATLAB
            comp.EditField_Version_MATLAB = uieditfield(comp.GridLayout, 'text');
            comp.EditField_Version_MATLAB.FontSize = 14;
            comp.EditField_Version_MATLAB.Layout.Row = 6;
            comp.EditField_Version_MATLAB.Layout.Column = 2;

            % Create ListBoxLabel
            comp.ListBoxLabel = uilabel(comp.GridLayout);
            comp.ListBoxLabel.HorizontalAlignment = 'right';
            comp.ListBoxLabel.FontSize = 14;
            comp.ListBoxLabel.Layout.Row = 7;
            comp.ListBoxLabel.Layout.Column = 1;
            comp.ListBoxLabel.Text = '已安装工具箱';

            % Create ListBox_InstalledToolbox
            comp.ListBox_InstalledToolbox = uilistbox(comp.GridLayout);
            comp.ListBox_InstalledToolbox.Items = {};
            comp.ListBox_InstalledToolbox.FontSize = 14;
            comp.ListBox_InstalledToolbox.Layout.Row = 7;
            comp.ListBox_InstalledToolbox.Layout.Column = 2;
            comp.ListBox_InstalledToolbox.Value = {};

            % Create JavaEditFieldLabel
            comp.JavaEditFieldLabel = uilabel(comp.GridLayout);
            comp.JavaEditFieldLabel.HorizontalAlignment = 'right';
            comp.JavaEditFieldLabel.FontSize = 14;
            comp.JavaEditFieldLabel.Layout.Row = 8;
            comp.JavaEditFieldLabel.Layout.Column = 1;
            comp.JavaEditFieldLabel.Text = 'Java 版本';

            % Create EditField_Version_Java
            comp.EditField_Version_Java = uieditfield(comp.GridLayout, 'text');
            comp.EditField_Version_Java.FontSize = 14;
            comp.EditField_Version_Java.Layout.Row = 8;
            comp.EditField_Version_Java.Layout.Column = 2;

            % Create PythonEditFieldLabel
            comp.PythonEditFieldLabel = uilabel(comp.GridLayout);
            comp.PythonEditFieldLabel.HorizontalAlignment = 'right';
            comp.PythonEditFieldLabel.FontSize = 14;
            comp.PythonEditFieldLabel.Layout.Row = 9;
            comp.PythonEditFieldLabel.Layout.Column = 1;
            comp.PythonEditFieldLabel.Text = 'Python 版本';

            % Create EditField_Version_Python
            comp.EditField_Version_Python = uieditfield(comp.GridLayout, 'text');
            comp.EditField_Version_Python.FontSize = 14;
            comp.EditField_Version_Python.Layout.Row = 9;
            comp.EditField_Version_Python.Layout.Column = 2;

            % Create EditField_4Label
            comp.EditField_4Label = uilabel(comp.GridLayout);
            comp.EditField_4Label.HorizontalAlignment = 'right';
            comp.EditField_4Label.FontSize = 14;
            comp.EditField_4Label.Layout.Row = 10;
            comp.EditField_4Label.Layout.Column = 1;
            comp.EditField_4Label.Text = '主程序版本';

            % Create EditField_Version_MainApp
            comp.EditField_Version_MainApp = uieditfield(comp.GridLayout, 'text');
            comp.EditField_Version_MainApp.FontSize = 14;
            comp.EditField_Version_MainApp.Layout.Row = 10;
            comp.EditField_Version_MainApp.Layout.Column = 2;

            % Create EditField_5Label
            comp.EditField_5Label = uilabel(comp.GridLayout);
            comp.EditField_5Label.HorizontalAlignment = 'right';
            comp.EditField_5Label.FontSize = 14;
            comp.EditField_5Label.Layout.Row = 11;
            comp.EditField_5Label.Layout.Column = 1;
            comp.EditField_5Label.Text = '采集器版本';

            % Create EditField_Version_Collector
            comp.EditField_Version_Collector = uieditfield(comp.GridLayout, 'text');
            comp.EditField_Version_Collector.FontSize = 14;
            comp.EditField_Version_Collector.Layout.Row = 11;
            comp.EditField_Version_Collector.Layout.Column = 2;

            % Create Panel_UserBehaviours
            comp.Panel_UserBehaviours = uipanel(comp.GridLayout2);
            comp.Panel_UserBehaviours.TitlePosition = 'centertop';
            comp.Panel_UserBehaviours.Title = '用户行为';
            comp.Panel_UserBehaviours.Layout.Row = 1;
            comp.Panel_UserBehaviours.Layout.Column = 2;
            comp.Panel_UserBehaviours.FontSize = 14;

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.Panel_UserBehaviours);
            comp.GridLayout3.ColumnWidth = {'1x'};
            comp.GridLayout3.RowHeight = {'1x'};
            comp.GridLayout3.Padding = [0 0 0 0];

            % Create UITable_Info_User
            comp.UITable_Info_User = uitable(comp.GridLayout3);
            comp.UITable_Info_User.ColumnName = {'时间'; '子程序'; '回调'; '异常'};
            comp.UITable_Info_User.RowName = {};
            comp.UITable_Info_User.ColumnSortable = true;
            comp.UITable_Info_User.SelectionType = 'row';
            comp.UITable_Info_User.Multiselect = 'off';
            comp.UITable_Info_User.Layout.Row = 1;
            comp.UITable_Info_User.Layout.Column = 1;
            comp.UITable_Info_User.FontSize = 14;
        end
    end
end