classdef reg3d_improvement_assistant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImprovementAssistantUIFigure  matlab.ui.Figure
        Menu_File                     matlab.ui.container.Menu
        Menu_OpenFile                 matlab.ui.container.Menu
        Menu_OpenFolder               matlab.ui.container.Menu
        Menu_Export                   matlab.ui.container.Menu
        Menu_Stat                     matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        ContextMenu_SheetAdded        matlab.ui.container.ContextMenu
        Menu_Delete                   matlab.ui.container.Menu
    end

    properties (Access = public)
        TabGroup                      matlab.ui.container.TabGroup
    end

    properties (Access = private)
        caller
        locker
        n_loaded
        r_loaded
        info_pages
    end
    
    methods (Access = private)
        function InitData(app)
            app.n_loaded = 0;
            app.r_loaded = {};
            app.info_pages = {};
            app.locker = aesobj();
        end

        function InitAppearance(app)
            InstallTabGroup(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshMessageLevel(app);

            RefreshTooltips(app);
        end

        function InstallTabGroup(app)
            % Create TabGroup
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.Layout.Row = 1;
            app.TabGroup.Layout.Column = 1;
        end

        function r = LoadFromFiles(app, files)
            arguments
                app
                files    (:,1)   string  {mustBeFile}
            end

            r = [];
            dpt = @(x)app.locker.decrypt(x);

            for n = 1:numel(files)
                try
                    dr = struct("EnvInfo",[], "UserInfo",[]);
                    % read file
                    fid = fopen(files(n), "r");
                    C = textscan(fid, "%s", "Delimiter", "\r\n", "CommentStyle","#", ...
                        "TextType","string");
                    fclose(fid);

                    C = C{1};
                    C(C.strlength==0) = [];     % remove empty rows

                    % collect rows with [] as environmental info
                    env_mask = C.contains("[")&C.contains("]");
                    env_strs = C(env_mask);
                    env_vars = env_strs.extractBetween("[","]").erase(" ");
                    env_data = env_strs.extractAfter("] ");
                    % decrypt the first data
                    env_data(1) = app.locker.decrypt(env_data(1));

                    % dynamic transform to struct
                    for k = 1:numel(env_vars)
                        switch env_vars(k)
                            case "ToolboxInstalled"
                                dr.EnvInfo.(env_vars(k)) = env_data(k).split(" | ");
                            otherwise
                                dr.EnvInfo.(env_vars(k)) = env_data(k);
                        end
                    end

                    % collect rows without [] as user's info
                    user_data = C(~env_mask);
                    % decrypt user data
                    user_data = arrayfun(dpt, user_data, "UniformOutput",true);
                    % transform to table
                    user_data = user_data.split(" | ");

                    dr.UserInfo = array2table(user_data, "VariableNames", ...
                        {'Time', 'Name', 'Callback', 'Exception'});

                    r = [r; dr]; %#ok<AGROW>
                catch ME
                    app.caller.PushInfo("reg3d_improvement_assistant", ...
                        "LoadFromFiles", ME.identifier);

                    rethrow(ME);
                end
            end
        end

        function AppendOneSheet(app, file)
            arguments
                app
                file    (1,:)   char
            end

            % Create Tab_Sheet1
            ts = uitab(app.TabGroup);
            ts.Tooltip = {file};
            ts.Title = sprintf("Sheet%d", app.n_loaded);
            ts.Tag = string(app.n_loaded);

            % Create GridLayout_Sheet1
            gs = uigridlayout(ts);
            gs.ColumnWidth = {'1x'};
            gs.RowHeight = {'1x'};
            gs.Padding = [0 0 0 0];

            % Create InfoPage
            ip = InfoPage(gs);
            ip.Layout.Row = 1;
            ip.Layout.Column = 1;
            % set data
            ip.EnvInfo = app.r_loaded{end}.EnvInfo;
            ip.UserInfo = app.r_loaded{end}.UserInfo;
            ip.IsEnable = true;
            app.info_pages{app.n_loaded} = ip;  % save

            % Assign app.ContextMenu_SheetAdded
            ts.ContextMenu = app.ContextMenu_SheetAdded;
        end
    end
    
    methods (Access = public)
        
        function RefreshPanelStyle(app)
            % This function refreshes style
            %   The plugin supports "Light" and "Dark" style

        end

        function RefreshPanelLanguage(app)
            fprintf("test\n");
        end

        function RefreshMessageLevel(app)

        end

        function RefreshTooltips(app)

        end

        function Close(app)
            delete(app)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;

            InitData(app);

            InitAppearance(app);
        end

        % Menu selected function: Menu_Delete
        function Menu_DeleteSelected(app, event)
            fprintf("delete\n");
        end

        % Menu selected function: Menu_OpenFile
        function Menu_OpenFileSelected(app, event)
            [file_name, path] = uigetfile({'*.log', 'reg3d日志文件(*.log)'}, ...
                "选择日志文件", "MultiSelect","on");

            if ~isnumeric(file_name)
                files = path + string(file_name);

                r = LoadFromFiles(app, files);

                for k = 1:size(r, 1)
                    app.r_loaded = [app.r_loaded, {r(k)}]; % append new r
                    app.n_loaded = app.n_loaded + 1;
                    AppendOneSheet(app, file_name{k});
                end
            end
        end

        % Menu selected function: Menu_OpenFolder
        function Menu_OpenFolderSelected(app, event)
            % load all file from given folder
            folder = uigetdir(userpath, "选择日志文件夹");
            if ~isnumeric(folder)
                files = struct2table(dir(folder));
                fmask = string(files.name).contains(constdef.LOG_FILE_EXT);
                file_name = string(files.name);
                files = string(files.folder) + filesep + string(files.name);
                files = files(fmask);
                file_name = file_name(fmask);

                r = LoadFromFiles(app, files);

                for k = 1:size(r, 1)
                    app.r_loaded = [app.r_loaded, {r(k)}]; % append new r
                    app.n_loaded = app.n_loaded + 1;
                    AppendOneSheet(app, file_name(k));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ImprovementAssistantUIFigure = uifigure('Visible', 'off');
            app.ImprovementAssistantUIFigure.Name = 'Improvement Assistant';
            app.ImprovementAssistantUIFigure.Icon = fullfile(pathToMLAPP, 'dependencies', 'icon.png');
            app.Menu_File = uimenu(app.ImprovementAssistantUIFigure);
            app.Menu_File.Text = '文件';
            app.Menu_OpenFile = uimenu(app.Menu_File);
            app.Menu_OpenFile.MenuSelectedFcn = createCallbackFcn(app, @Menu_OpenFileSelected, true);
            app.Menu_OpenFile.Text = '打开文件';
            app.Menu_OpenFolder = uimenu(app.Menu_File);
            app.Menu_OpenFolder.MenuSelectedFcn = createCallbackFcn(app, @Menu_OpenFolderSelected, true);
            app.Menu_OpenFolder.Text = '打开文件夹';
            app.Menu_Export = uimenu(app.Menu_File);
            app.Menu_Export.Separator = 'on';
            app.Menu_Export.Text = '导出';
            app.Menu_Stat = uimenu(app.ImprovementAssistantUIFigure);
            app.Menu_Stat.Text = '统计';
            app.GridLayout = uigridlayout(app.ImprovementAssistantUIFigure);
            app.ContextMenu_SheetAdded = uicontextmenu(app.ImprovementAssistantUIFigure);
            app.Menu_Delete = uimenu(app.ContextMenu_SheetAdded);
            app.Menu_Delete.MenuSelectedFcn = createCallbackFcn(app, @Menu_DeleteSelected, true);
            app.Menu_Delete.Text = '删除';
            % Show the figure after all components are created
            app.ImprovementAssistantUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reg3d_improvement_assistant(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ImprovementAssistantUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ImprovementAssistantUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImprovementAssistantUIFigure)
        end
    end
end