classdef BCAdjuster < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BCAdjusterUIFigure      matlab.ui.Figure
        Panel_Control           matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        Button_GammaMinus       matlab.ui.control.Button
        Button_GammaPlus        matlab.ui.control.Button
        Slider_Gamma            matlab.ui.control.Slider
        Slider_4Label_2         matlab.ui.control.Label
        Button_ContrastMinus    matlab.ui.control.Button
        Button_ContrastPlus     matlab.ui.control.Button
        Slider_Contrast         matlab.ui.control.Slider
        Slider_4Label_3         matlab.ui.control.Label
        Button_BrightnessPlus   matlab.ui.control.Button
        Button_BrightnessMinus  matlab.ui.control.Button
        Slider_Brightness       matlab.ui.control.Slider
        Slider_3Label           matlab.ui.control.Label
        Panel_View              matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        UIAxes_Hist             matlab.ui.control.UIAxes
    end

    properties (GetAccess = ?BugReporter, Constant)
        VER = "1.0.0"
    end

    properties(Constant, Hidden)
        GAMMA_SCALE = 100
    end

    properties (Access = private)
        caller          % the parent app

        % image intensity model: y = c*x^ga+b
        img_params      % struct, field {c, b, ga}, c for contrast coefficient
                        % b for brightness coefficient, ga for gamma value.

        intensity       % the image intensity
        c_angle         % the contrast transform angle
        channel_cur     % the current channel, could be "r","g"

        langmap         % mapping for language
    end

    methods(Access=?Register)
        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Panel_View.Title = app.langmap.PIXEL_HISTOGRAM;
            app.Panel_Control.Title = app.langmap.CONTROL_PANEL;
            app.Slider_3Label.Text = app.langmap.LIGHT;
            app.Slider_4Label_3.Text = app.langmap.CONTRAST;
            app.Slider_4Label_2.Text = app.langmap.GAMMA;
        end

        function Close(app)
            BCAdjusterUIFigureCloseRequest(app, []);
        end

        function Refresh(app)
            InitData(app);

            InitViewerPanel(app);

            InitControlPanel(app);
        end
    end
    
    methods (Access = private)

        function results = InitViewerPanel(app)
            app.UIAxes_Hist.Toolbar = [];
            % disable UIAxes interaction
            disableDefaultInteractivity(app.UIAxes_Hist);

            UpdateUIAxes_Hist(app);

            set(app.UIAxes_Hist,"FontSize",16);

            results = 0;
        end

        function results = InitControlPanel(app)
            %
            app.Slider_Brightness.Limits = double([-max(app.intensity), ...
                intmax("uint16")-max(app.intensity)]);
            app.Slider_Brightness.Value = app.img_params.b;
            %
            app.Slider_Contrast.Limits = [0, fix(atan(double(intmax("uint16"))...
                /max(1,double(quantile(app.intensity,0.05))))/pi*180)];
            app.c_angle = atan(app.img_params.c)/pi*180;
            app.Slider_Contrast.Value = app.c_angle;    % contrast slider use angle
            
            %
            app.Slider_Gamma.Limits = [0,3];
            app.Slider_Gamma.Value = app.img_params.ga;

            results = 0;
        end

        function UpdateUIAxes_Hist(app)
            % histogram
            h = histogram(app.UIAxes_Hist,app.intensity,...
                "Normalization","probability",...
                "FaceColor",app.channel_cur,"EdgeColor","none");
            hold(app.UIAxes_Hist,"on");

            % generate the curve
            x = double(min(app.intensity):max(app.intensity));
            xx = linspace(0, double(intmax("uint16")), numel(x));
            y = app.img_params.c * xx.^app.img_params.ga + app.img_params.b;

            % rescale y value
            y = y / double(intmax("uint16")) * max(h.Values);
            plot(app.UIAxes_Hist,x,y,"LineWidth",2,"LineStyle","-","Color","k");
            hold(app.UIAxes_Hist,"off");

            if min(app.intensity) + 10*eps < max(app.intensity)
                xticks(app.UIAxes_Hist,[min(app.intensity), ...
                    max(app.intensity)]);
                xlim(app.UIAxes_Hist,[min(app.intensity), ...
                    max(app.intensity)]);
            else
                % app.intensity is alpha*ones
                xticks(app.UIAxes_Hist, [min(app.intensity)-0.5,...
                    max(app.intensity)+0.5]);
                xlim(app.UIAxes_Hist, [min(app.intensity)-0.5,...
                    max(app.intensity)+0.5]);
            end
            ylim(app.UIAxes_Hist, [0 1.05*max(h.Values)]);
        end
        
        function InitData(app)
            app.img_params = app.caller.GetBCG();

            UpdateData(app);
        end

        function UpdateData(app)
            image_cur = app.caller.GetCurrentSliceImg();
            app.intensity = reshape(image_cur,[],1);
            app.channel_cur = app.caller.GetCurrentChannel();
        end

        function UpdateSetting(app)
            % update the BC_GUI Setting
            % note: SetBCG function also called caller refresh the viewer
            % immediately
            app.caller.SetBCG(app.img_params);
            UpdateData(app);
            UpdateUIAxes_Hist(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.caller = mainapp;
            
            InitData(app);

            InitViewerPanel(app);

            InitControlPanel(app);

            RefreshPanelLanguage(app);
        end

        % Value changing function: Slider_Brightness
        function Slider_BrightnessValueChanging(app, event)
            changingValue = event.Value;
            app.img_params.b = changingValue;

            UpdateSetting(app);
        end

        % Button down function: Panel_View
        function Panel_ViewButtonDown(app, event)
            % if button down, we need to get the current image setting 
            % info from the caller
            UpdateData(app);
            UpdateUIAxes_Hist(app);
        end

        % Button pushed function: Button_BrightnessMinus
        function Button_BrightnessMinusPushed(app, event)
            db = double(intmax("uint16"))/100;
            if app.img_params.b - db >= app.Slider_Brightness.Limits(1)
                app.img_params.b = app.img_params.b - db;
                app.Slider_Brightness.Value = app.img_params.b;
            end

            UpdateSetting(app)
        end

        % Button pushed function: Button_BrightnessPlus
        function Button_BrightnessPlusPushed(app, event)
            db = double(intmax("uint16"))/100;
            if app.img_params.b + db <= app.Slider_Brightness.Limits(end)
                app.img_params.b = app.img_params.b + db;
                app.Slider_Brightness.Value = app.img_params.b;
            end

            UpdateSetting(app)
        end

        % Button pushed function: Button_ContrastMinus
        function Button_ContrastMinusPushed(app, event)
            if app.c_angle - 1 >= app.Slider_Contrast.Limits(1)
                app.c_angle = app.c_angle - 1;
                app.Slider_Contrast.Value = app.c_angle;
                app.img_params.c = tan(app.c_angle/180*pi);
            end

            UpdateSetting(app)
        end

        % Button pushed function: Button_ContrastPlus
        function Button_ContrastPlusPushed(app, event)
            if app.c_angle + 1 <= app.Slider_Contrast.Limits(end)
                app.c_angle = app.c_angle + 1;
                app.Slider_Contrast.Value = app.c_angle;
                app.img_params.c = tan(app.c_angle/180*pi);
            end

           UpdateSetting(app)
        end

        % Value changing function: Slider_Contrast
        function Slider_ContrastValueChanging(app, event)
            changingValue = event.Value;
            app.c_angle = changingValue;
            app.img_params.c = tan(app.c_angle/180*pi);

            UpdateSetting(app)
        end

        % Button pushed function: Button_GammaMinus
        function Button_GammaMinusPushed(app, event)
            dga = 1/app.GAMMA_SCALE*diff(app.Slider_Gamma.Limits);

            if app.img_params.ga - dga >= app.Slider_Gamma.Limits(1)
                app.img_params.ga = app.img_params.ga - ...
                    1/app.GAMMA_SCALE*diff(app.Slider_Gamma.Limits);
                app.Slider_Gamma.Value = app.img_params.ga;
            end

            UpdateSetting(app)
        end

        % Button pushed function: Button_GammaPlus
        function Button_GammaPlusPushed(app, event)
            dga = 1/app.GAMMA_SCALE*diff(app.Slider_Gamma.Limits);

            if app.img_params.ga +dga <= app.Slider_Gamma.Limits(end)
                app.img_params.ga = app.img_params.ga + ...
                    1/app.GAMMA_SCALE*diff(app.Slider_Gamma.Limits);
                app.Slider_Gamma.Value = app.img_params.ga;
            end

            UpdateSetting(app)
        end

        % Value changing function: Slider_Gamma
        function Slider_GammaValueChanging(app, event)
            changingValue = event.Value;
            app.img_params.ga = changingValue;

            UpdateSetting(app)
        end

        % Button down function: Panel_Control
        function Panel_ControlButtonDown(app, event)
            % if button down, we need to get the current image setting
            % info from the caller
            UpdateData(app);
            UpdateUIAxes_Hist(app);
        end

        % Close request function: BCAdjusterUIFigure
        function BCAdjusterUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BCAdjusterUIFigure = uifigure('Visible', 'off');
            app.BCAdjusterUIFigure.Name = 'BC Adjuster';
            app.BCAdjusterUIFigure.Icon = 'image_gui.png';
            app.BCAdjusterUIFigure.CloseRequestFcn = createCallbackFcn(app, @BCAdjusterUIFigureCloseRequest, true);
            app.Panel_View = uipanel(app.BCAdjusterUIFigure);
            app.Panel_View.Title = '像素直方图';
            app.Panel_View.ButtonDownFcn = createCallbackFcn(app, @Panel_ViewButtonDown, true);
            app.TabGroup = uitabgroup(app.Panel_View);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes_Hist = uiaxes(app.Tab);
            app.UIAxes_Hist.XTick = [];
            app.UIAxes_Hist.YTick = [];
            app.Panel_Control = uipanel(app.BCAdjusterUIFigure);
            app.Panel_Control.Title = '控制面板';
            app.Panel_Control.ButtonDownFcn = createCallbackFcn(app, @Panel_ControlButtonDown, true);
            app.GridLayout = uigridlayout(app.Panel_Control);
            app.Slider_3Label = uilabel(app.GridLayout);
            app.Slider_3Label.Text = '亮度';
            app.Slider_Brightness = uislider(app.GridLayout);
            app.Slider_Brightness.Limits = [-1 1];
            app.Slider_Brightness.MajorTicks = [];
            app.Slider_Brightness.ValueChangingFcn = createCallbackFcn(app, @Slider_BrightnessValueChanging, true);
            app.Slider_Brightness.MinorTicks = [];
            app.Button_BrightnessMinus = uibutton(app.GridLayout, 'push');
            app.Button_BrightnessMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_BrightnessMinusPushed, true);
            app.Button_BrightnessMinus.Text = '◀';
            app.Button_BrightnessPlus = uibutton(app.GridLayout, 'push');
            app.Button_BrightnessPlus.ButtonPushedFcn = createCallbackFcn(app, @Button_BrightnessPlusPushed, true);
            app.Button_BrightnessPlus.Text = '▶';
            app.Slider_4Label_3 = uilabel(app.GridLayout);
            app.Slider_4Label_3.Text = '对比度';
            app.Slider_Contrast = uislider(app.GridLayout);
            app.Slider_Contrast.MajorTicks = [];
            app.Slider_Contrast.ValueChangingFcn = createCallbackFcn(app, @Slider_ContrastValueChanging, true);
            app.Slider_Contrast.MinorTicks = [];
            app.Slider_Contrast.Value = 50;
            app.Button_ContrastPlus = uibutton(app.GridLayout, 'push');
            app.Button_ContrastPlus.ButtonPushedFcn = createCallbackFcn(app, @Button_ContrastPlusPushed, true);
            app.Button_ContrastPlus.Text = '▶';
            app.Button_ContrastMinus = uibutton(app.GridLayout, 'push');
            app.Button_ContrastMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_ContrastMinusPushed, true);
            app.Button_ContrastMinus.Text = '◀';
            app.Slider_4Label_2 = uilabel(app.GridLayout);
            app.Slider_4Label_2.Text = '伽马';
            app.Slider_Gamma = uislider(app.GridLayout);
            app.Slider_Gamma.MajorTicks = [];
            app.Slider_Gamma.ValueChangingFcn = createCallbackFcn(app, @Slider_GammaValueChanging, true);
            app.Slider_Gamma.MinorTicks = [];
            app.Slider_Gamma.Value = 50;
            app.Button_GammaPlus = uibutton(app.GridLayout, 'push');
            app.Button_GammaPlus.ButtonPushedFcn = createCallbackFcn(app, @Button_GammaPlusPushed, true);
            app.Button_GammaPlus.Text = '▶';
            app.Button_GammaMinus = uibutton(app.GridLayout, 'push');
            app.Button_GammaMinus.ButtonPushedFcn = createCallbackFcn(app, @Button_GammaMinusPushed, true);
            app.Button_GammaMinus.Text = '◀';
            % Show the figure after all components are created
            app.BCAdjusterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BCAdjuster(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BCAdjusterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BCAdjusterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BCAdjusterUIFigure)
        end
    end
end