classdef BugReporter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BugReporterUIFigure          matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        Panel_ProblemComponents      matlab.ui.container.Panel
        GridLayout_PC                matlab.ui.container.GridLayout
        Tree_ProblemComponents       matlab.ui.container.CheckBoxTree
        Node                         matlab.ui.container.TreeNode
        Node4                        matlab.ui.container.TreeNode
        Node3                        matlab.ui.container.TreeNode
        Node2                        matlab.ui.container.TreeNode
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout9                  matlab.ui.container.GridLayout
        Button_SelectFolder          matlab.ui.control.Button
        EditField_Folder             matlab.ui.control.EditField
        Label_4                      matlab.ui.control.Label
        Panel_ProblemDescription     matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        TextArea_ProblemDescription  matlab.ui.control.TextArea
        GridLayout7                  matlab.ui.container.GridLayout
        ButtonGroup_ProblemType      matlab.ui.container.ButtonGroup
        Button_Suggested             matlab.ui.control.RadioButton
        Button_Normal                matlab.ui.control.RadioButton
        Button_Urgent                matlab.ui.control.RadioButton
        GridLayout8                  matlab.ui.container.GridLayout
        Button_Submit                matlab.ui.control.Button
        GridLayout2                  matlab.ui.container.GridLayout
        Label_3                      matlab.ui.control.Label
        Label_RemoteHostStatus       matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, status)
            if status == 0
                app.Label_RemoteHostStatus.Text = "联机";
                app.Label_RemoteHostStatus.FontColor = [0, 1, 0];
            elseif status == -1
                app.Label_RemoteHostStatus.Text = "脱机";
                app.Label_RemoteHostStatus.FontColor = [1, 0, 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BugReporterUIFigure = uifigure('Visible', 'off');
            app.BugReporterUIFigure.Name = 'Bug Reporter';
            app.BugReporterUIFigure.Icon = 'bug.png';
            app.GridLayout = uigridlayout(app.BugReporterUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '问题报告程序：';
            app.Label_RemoteHostStatus = uilabel(app.GridLayout2);
            app.Label_RemoteHostStatus.Text = '脱机';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '问题报告程序不会收集您的隐私';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.Button_Submit = uibutton(app.GridLayout8, 'push');
            app.Button_Submit.Text = '提交';
            app.ButtonGroup_ProblemType = uibuttongroup(app.GridLayout7);
            app.ButtonGroup_ProblemType.Title = '问题类型';
            app.Button_Urgent = uiradiobutton(app.ButtonGroup_ProblemType);
            app.Button_Urgent.Text = '紧急';
            app.Button_Urgent.Value = true;
            app.Button_Normal = uiradiobutton(app.ButtonGroup_ProblemType);
            app.Button_Normal.Text = '普通';
            app.Button_Suggested = uiradiobutton(app.ButtonGroup_ProblemType);
            app.Button_Suggested.Text = '建议';
            app.Panel_ProblemDescription = uipanel(app.GridLayout4);
            app.Panel_ProblemDescription.Title = '问题描述';
            app.GridLayout5 = uigridlayout(app.Panel_ProblemDescription);
            app.TextArea_ProblemDescription = uitextarea(app.GridLayout5);
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.Label_4 = uilabel(app.GridLayout9);
            app.Label_4.Text = '附加文件夹';
            app.EditField_Folder = uieditfield(app.GridLayout9, 'text');
            app.EditField_Folder.Placeholder = '此处包含用于复现问题的附加文件';
            app.Button_SelectFolder = uibutton(app.GridLayout9, 'push');
            app.Button_SelectFolder.Icon = 'folder.png';
            app.Button_SelectFolder.Text = '';
            app.Panel_ProblemComponents = uipanel(app.GridLayout3);
            app.Panel_ProblemComponents.Title = '问题组件';
            app.GridLayout_PC = uigridlayout(app.Panel_ProblemComponents);
            app.Tree_ProblemComponents = uitree(app.GridLayout_PC, 'checkbox');
            app.Node = uitreenode(app.Tree_ProblemComponents);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            % Show the figure after all components are created
            app.BugReporterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BugReporter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BugReporterUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BugReporterUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BugReporterUIFigure)
        end
    end
end