classdef LabNasLogin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LabNasLoginUIFigure  matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel_LabNas         matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        Button_Cancel        matlab.ui.control.Button
        Button_Login         matlab.ui.control.Button
        GridLayout4          matlab.ui.container.GridLayout
        Button_HidePassword  matlab.ui.control.Button
        EditField_Password   matlab.ui.control.EditField
        PasswordLabel        matlab.ui.control.Label
        GridLayout3          matlab.ui.container.GridLayout
        EditField_UserName   matlab.ui.control.EditField
        Label                matlab.ui.control.Label
    end

    properties (GetAccess = ?BugReporter, Constant)
        VER = "1.0.0"
    end

    properties (Access = private)
        pwd_plain      % the previous password
        pwd_status     % the password status
        aes_locker     % an aes object
    end

    properties (GetAccess=public, SetAccess=private)
        username       % plain text user name
        password       % encrypted password
        login_status   % the login button pushed status, public for trigger
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pwd_plain = [];
            app.pwd_status = "hide";
            if ispc()
                app.aes_locker = aesobj();
            elseif isunix()
                app.aes_locker = [];
            end

            app.login_status = false;
            app.Button_Login.Enable = "off";
        end

        % Button pushed function: Button_Login
        function Button_LoginPushed(app, event)
            % set user data
            app.username = app.EditField_UserName.Value;
            if ispc()
                app.password = app.aes_locker.encrypt(app.pwd_plain);
            elseif isunix()
                app.password = app.pwd_plain;
            end
            
            % change the status, trigger outer caller
            app.login_status = true;
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            delete(app);
        end

        % Button pushed function: Button_HidePassword
        function Button_HidePasswordPushed(app, event)
            switch app.pwd_status
                case "hide"
                    app.pwd_status = "uncover";
                    app.Button_HidePassword.Icon ...
                        = "eye_open.png";
                    app.EditField_Password.Value = app.pwd_plain;
                case "uncover"
                    app.pwd_status = "hide";
                    app.Button_HidePassword.Icon ...
                        = "eye_close.png";
                    % use mask to hide the password
                    app.EditField_Password.Value = ...
                        repmat('*',1,numel(app.pwd_plain));
            end
        end

        % Value changing function: EditField_Password
        function EditField_PasswordValueChanging(app, event)
            pwd_cur = event.Value;
            
            d = length(pwd_cur) - length(app.pwd_plain);
            if d > 0
                app.pwd_plain = [app.pwd_plain, pwd_cur(end-d+1:end)];
                if app.pwd_status == "hide"
                    app.EditField_Password.Value = ...
                        repmat('*',1,numel(app.pwd_plain));
                end
            elseif d < 0
                app.pwd_plain(end+d+1:end) = [];
            end

            if ~isempty(pwd_cur) && ~isempty(app.EditField_UserName.Value)
                app.Button_Login.Enable = "on";
            else
                app.Button_Login.Enable = "off";
            end
        end

        % Value changing function: EditField_UserName
        function EditField_UserNameValueChanging(app, event)
            user = event.Value;
            if ~isempty(user) && ~isempty(app.EditField_Password.Value)
                app.Button_Login.Enable = "on";
            else
                app.Button_Login.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LabNasLoginUIFigure = uifigure('Visible', 'off');
            app.LabNasLoginUIFigure.Name = 'LabNasLogin';
            app.LabNasLoginUIFigure.Icon = 'lock.png';
            app.GridLayout = uigridlayout(app.LabNasLoginUIFigure);
            app.Panel_LabNas = uipanel(app.GridLayout);
            app.Panel_LabNas.Title = 'Login — LabNas';
            app.GridLayout2 = uigridlayout(app.Panel_LabNas);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = 'User Name';
            app.EditField_UserName = uieditfield(app.GridLayout3, 'text');
            app.EditField_UserName.ValueChangingFcn = createCallbackFcn(app, @EditField_UserNameValueChanging, true);
            app.EditField_UserName.Placeholder = 'Enter your ID';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.PasswordLabel = uilabel(app.GridLayout4);
            app.PasswordLabel.Text = 'Password';
            app.EditField_Password = uieditfield(app.GridLayout4, 'text');
            app.EditField_Password.ValueChangingFcn = createCallbackFcn(app, @EditField_PasswordValueChanging, true);
            app.EditField_Password.Placeholder = 'Enter password';
            app.Button_HidePassword = uibutton(app.GridLayout4, 'push');
            app.Button_HidePassword.ButtonPushedFcn = createCallbackFcn(app, @Button_HidePasswordPushed, true);
            app.Button_HidePassword.Icon = 'eye_close.png';
            app.Button_HidePassword.Text = '';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.Button_Login = uibutton(app.GridLayout5, 'push');
            app.Button_Login.ButtonPushedFcn = createCallbackFcn(app, @Button_LoginPushed, true);
            app.Button_Login.Text = 'Login';
            app.Button_Cancel = uibutton(app.GridLayout5, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.LabNasLoginUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LabNasLogin

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.LabNasLoginUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.LabNasLoginUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LabNasLoginUIFigure)
        end
    end
end