classdef NewProjectConfigurator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfiguratorUIFigure      matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout5               matlab.ui.container.GridLayout
        Button_Cancel             matlab.ui.control.Button
        Button_Create             matlab.ui.control.Button
        Button_ProjectSetting     matlab.ui.control.Button
        CheckBox_IgnoreImport     matlab.ui.control.CheckBox
        GridLayout4               matlab.ui.container.GridLayout
        DropDown_ProjectTemplate  matlab.ui.control.DropDown
        Label_3                   matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        DropDown_ProjectLocation  matlab.ui.control.DropDown
        Label_2                   matlab.ui.control.Label
        GridLayout2               matlab.ui.container.GridLayout
        EditField_ProjectName     matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Access = private)
        caller      % 1-by-1 Register object
        pos_caller  % 1-by-4 double array with integer
        config      % 1-by-1 struct with project configuration
        langmap     % 1-by-1 struct with language mapping
    end
    
    methods (Access = ?Register)
        
        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr > 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    
                case "DARK"
                    
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            % get language map
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            % apply languages
            app.Label.Text = app.langmap.PROJECT_NAME;
            app.Label_2.Text = app.langmap.POSITION;
            app.Label_3.Text = app.langmap.TEMPLATE;
            app.CheckBox_IgnoreImport.Text = app.langmap.IGNORE_IMPORT_STEP;
            app.Button_Create.Text = app.langmap.CREATION;
            app.Button_Cancel.Text = app.langmap.CANCEL;
        end

        function RefreshTooltips(app)
            
        end
    end

    methods (Access = ?ProjectConfiguratorSetting)
        function [lang, mapping] = GetLangsInfo(app)
            [lang, mapping] = app.caller.GetLangsInfo();
        end
    end

    methods (Access = private)
        function InitData(app)
            % get current config from caller
            app.config = app.caller.GetProjectConfiguration();

            app.pos_caller = app.caller.GetAppPosition();

            % apply
            app.EditField_ProjectName.Value = app.config.ProjectName;
            app.DropDown_ProjectLocation.Items = ...
                [app.config.ProjectFolders; "Select Location"];
            n_items_exist = numel(app.DropDown_ProjectLocation.Items) - 1;
            app.DropDown_ProjectLocation.ItemsData = string([(1:n_items_exist)'; 0]);
            app.DropDown_ProjectTemplate.Value = app.config.Template;
            app.CheckBox_IgnoreImport.Value = (app.config.IgnoreImport=="ON");
        end

        function InitAppearance(app)
            SetupWindowPosition(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);

            RefreshTooltips(app);
        end

        function SetupWindowPosition(app)
            x0 = app.pos_caller(1); y0 = app.pos_caller(2);
            w0 = app.pos_caller(3); h0 = app.pos_caller(4);
            w = app.ConfiguratorUIFigure.Position(3);
            h = app.ConfiguratorUIFigure.Position(4);
            x = ceil(x0 + 0.5*(w0 - w));
            y = ceil(y0 + 0.5*(h0 - h));
            app.ConfiguratorUIFigure.Position(1) = x;
            app.ConfiguratorUIFigure.Position(2) = y;
        end

        function SaveConfig(app)
            prf_file = app.caller.GetProjectConfigurationFullPath();
            [fpath, ~, ~] = fileparts(prf_file);
            if ~isfolder(fpath)
                try mkdir(fpath); catch ME, throw(ME); end
            end

            try
                writestruct(app.config, prf_file, "FileType","xml");
            catch ME
                throw(MException("NewProjectConfigurator:permissionDenied", ...
                    app.langmap.PERMISSION_DENIED));
            end
        end

        function Close(app)
            % ~
            delete(app)
        end
    end

    methods (Static)
        function r = ValidateFileName(s)
            arguments
                s   (1,1)   string
            end

            if ispc()
                r1 = all(~s.contains(["<", ">", ":", """", "/", "\", ...
                    "|", "?", "*"]));
                r2 = all(~ismember(s, ["CON", "PRN", "AUX", "NUL", "COM1", ...
                    "COM2", "COM3", "COM4", "COM5", "COM6", "COM7", "COM8", ...
                    "COM9", "LPT1", "LPT2", "LPT3", "LPT4", "LPT5", "LPT6", ...
                    "LPT7", "LPT8", "LPT9"]));
                r = r1 && r2;
            else
                % treat as unix
                r1 = all(~s.contains([" ", "/", "*", "?", "&", "|", """", ...
                    ":", ";", ",", "!", "(", ")", "'", "@", "#", "$", "%", ...
                    "^", "[", "]", "{", "}", "?", "\", "~", "`"]));
                r2 = all(~ismember(s, ["bin", "sbin", "etc"]));
                r = r1 && r2;
            end
        end

        function r = ValidateProjectPosition(s)
            arguments
                s   (1,1)   string
            end

            r = ~isfolder(s);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.caller = caller;

            InitData(app);

            InitAppearance(app);
        end

        % Value changed function: DropDown_ProjectLocation
        function DropDown_ProjectLocationValueChanged(app, event)
            value = app.DropDown_ProjectLocation.Value;
            
            switch value
                case "0"
                    % select and append new location
                    folder = uigetdir(userpath, app.langmap.PROJECT_FOLDER_SELECTION);
                    if ~isnumeric(folder)
                        folder = string(folder);
                        [p, loc] = ismember(folder, string(app.DropDown_ProjectLocation.Items));
                        if ~p
                            app.DropDown_ProjectLocation.Items = ...
                                [app.DropDown_ProjectLocation.Items(1:end-1), ...
                                folder, ...
                                "Select Location"];
                            n_items_exist = numel(app.DropDown_ProjectLocation.Items)-1;
                            app.DropDown_ProjectLocation.ItemsData = ...
                                string([(1:n_items_exist)'; 0]);
                            app.DropDown_ProjectLocation.Value = string(n_items_exist);
                        else
                            app.DropDown_ProjectLocation.Value = ...
                                string(loc);
                        end
                    end
                otherwise
                    % skip
            end
        end

        % Button pushed function: Button_ProjectSetting
        function Button_ProjectSettingPushed(app, event)
            pcs = ProjectConfiguratorSetting(app);
            waitfor(pcs);


        end

        % Button pushed function: Button_Create
        function Button_CreatePushed(app, event)
            % send config back to Register
            try
                % validate the project name
                project_name = string(app.EditField_ProjectName.Value);
                if NewProjectConfigurator.ValidateFileName(project_name)
                    pf_loc = (app.DropDown_ProjectLocation.Value ...
                        == app.DropDown_ProjectLocation.ItemsData);
                    project_folder = string(app.DropDown_ProjectLocation.Items(pf_loc));

                    if ~NewProjectConfigurator.ValidateProjectPosition(project_folder)
                        slt = uiconfirm(app.ConfiguratorUIFigure, ...
                            app.langmap.REPLACE_EXIST_PROJECT, ...
                            app.langmap.WARNING, "Icon","warning", ...
                            "Options", [app.langmap.REPLACE, app.langmap.CANCEL], ...
                            "DefaultOption", app.langmap.CANCEL);

                        switch slt
                            case app.langmap.CANCEL
                                return;
                            otherwise
                                % go ahead
                        end
                    end

                    app.config.ProjectName = project_name;
                    
                    app.config.ProjectFolder = string(app.DropDown_ProjectLocation.Items(pf_loc));
                    if ~ismember(app.config.ProjectFolder, app.config.ProjectFolders)
                        app.config.ProjectFolders = [app.config.ProjectFolders; ...
                            app.config.ProjectFolder];
                    end

                    app.config.Template = string(app.DropDown_ProjectTemplate.Value);
                    
                    if app.CheckBox_IgnoreImport.Value == true
                        app.config.IgnoreImport = "ON";
                    else
                        app.config.IgnoreImport = "OFF";
                    end

                    % set caller state
                    app.ConfiguratorUIFigure.Pointer = "watch";
                    drawnow;

                    app.caller.SetProjectConfiguration(app.config, true);
                    app.SaveConfig();

                    % close this
                    Close(app);
                else
                    % ERROR: display at anytime
                    uialert(app.ConfiguratorUIFigure, app.langmap.INVALID_FILE_NAME, ...
                        app.langmap.ERROR, "Icon","error", "Modal",true);
                end
            catch ME
                app.caller.PushInfo("NewProjectConfigurator", "Button_CreatePushed", ...
                    ME.identifier);

                rethrow(ME);
            end
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            % set no update flag
            app.caller.SetProjectConfiguration(app.config, false);

            % and close
            Close(app);
        end

        % Close request function: ConfiguratorUIFigure
        function ConfiguratorUIFigureCloseRequest(app, event)
            % set no update flag
            app.caller.SetProjectConfiguration(app.config, false);

            % and close
            Close(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfiguratorUIFigure = uifigure('Visible', 'off');
            app.ConfiguratorUIFigure.Name = 'Configurator';
            app.ConfiguratorUIFigure.Icon = 'new_project.png';
            app.ConfiguratorUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConfiguratorUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ConfiguratorUIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '项目名';
            app.EditField_ProjectName = uieditfield(app.GridLayout2, 'text');
            app.EditField_ProjectName.CharacterLimits = [0 255];
            app.EditField_ProjectName.Value = 'Untitled';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '位置';
            app.DropDown_ProjectLocation = uidropdown(app.GridLayout3);
            app.DropDown_ProjectLocation.Items = {'/home/Reg3D Projects', 'Select Position'};
            app.DropDown_ProjectLocation.ItemsData = {'1', '0'};
            app.DropDown_ProjectLocation.ValueChangedFcn = createCallbackFcn(app, @DropDown_ProjectLocationValueChanged, true);
            app.DropDown_ProjectLocation.Value = '1';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '模板';
            app.DropDown_ProjectTemplate = uidropdown(app.GridLayout4);
            app.DropDown_ProjectTemplate.Items = {'None', 'Standard Registration Workflow.regproj', 'Media Workflow.regproj', 'Developer Workflow.regproj'};
            app.DropDown_ProjectTemplate.ItemsData = {'NONE', 'STDREG', 'MEDIA', 'DEV'};
            app.DropDown_ProjectTemplate.Value = 'NONE';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.CheckBox_IgnoreImport = uicheckbox(app.GridLayout5);
            app.CheckBox_IgnoreImport.Text = '跳过导入模式';
            app.Button_ProjectSetting = uibutton(app.GridLayout5, 'push');
            app.Button_ProjectSetting.ButtonPushedFcn = createCallbackFcn(app, @Button_ProjectSettingPushed, true);
            app.Button_ProjectSetting.Icon = 'setting_single.png';
            app.Button_ProjectSetting.Text = '';
            app.Button_Create = uibutton(app.GridLayout5, 'push');
            app.Button_Create.ButtonPushedFcn = createCallbackFcn(app, @Button_CreatePushed, true);
            app.Button_Create.Text = '创建';
            app.Button_Cancel = uibutton(app.GridLayout5, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            % Show the figure after all components are created
            app.ConfiguratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewProjectConfigurator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConfiguratorUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConfiguratorUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfiguratorUIFigure)
        end
    end
end