classdef RegSetting_TC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RSTCUIFigure                    matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_Auto_Global                 matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_Auto_NS_Global              matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        Panel_NS_Reg                    matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        GridLayout15                    matlab.ui.container.GridLayout
        EditField_InitStep              matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        EditField_MinStep               matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        Image_CPU                       matlab.ui.control.Image
        GridLayout13                    matlab.ui.container.GridLayout
        DropDown_Functional_Global      matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        DropDown_Structured_Global      matlab.ui.control.DropDown
        Label_3                         matlab.ui.control.Label
        GridLayout12                    matlab.ui.container.GridLayout
        DropDown_RegTform_Auto          matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        DropDown_RegSampling_Global     matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        GridLayout11                    matlab.ui.container.GridLayout
        CheckBox_AutoTemplate           matlab.ui.control.CheckBox
        EditField_RegTemplate_Global    matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        Tab_Auto_AS_Global              matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        Label_VPL_Global                matlab.ui.control.Label
        Spinner_VPL_Global              matlab.ui.control.Spinner
        DropDown_InterpAlg_Auto_Global  matlab.ui.control.DropDown
        Label_9                         matlab.ui.control.Label
        GridLayout7                     matlab.ui.container.GridLayout
        EditField_IterCoeff             matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        EditField_MaxIterN_Global       matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        DropDown_RegModal               matlab.ui.control.DropDown
        Label_10                        matlab.ui.control.Label
        DropDown_DownSampling           matlab.ui.control.DropDown
        Label_13                        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        EditField_GaussianFilterSize    matlab.ui.control.EditField
        Label_52                        matlab.ui.control.Label
        EditField_OptimalZ_Tol          matlab.ui.control.NumericEditField
        Label_56                        matlab.ui.control.Label
        EditField_OptimalZ_MaxIterN     matlab.ui.control.NumericEditField
        Label_55                        matlab.ui.control.Label
        EditField_MedianFilterSize      matlab.ui.control.EditField
        Label_51                        matlab.ui.control.Label
        EditField_OpenOperatorSize      matlab.ui.control.EditField
        Label_53                        matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        CheckBox_GlobalLock             matlab.ui.control.CheckBox
        Tab_Auto_Local                  matlab.ui.container.Tab
        EditField_RegTemplate_Local     matlab.ui.control.EditField
        Label_21                        matlab.ui.control.Label
        EditField_MaxIterN_Local        matlab.ui.control.EditField
        Label_20                        matlab.ui.control.Label
        CheckBox_PhotonCompOn           matlab.ui.control.CheckBox
        DropDown_PhotonComAcc           matlab.ui.control.DropDown
        Label_19                        matlab.ui.control.Label
        DropDown_Signal_Local           matlab.ui.control.DropDown
        Label_18                        matlab.ui.control.Label
        EditField_AFS                   matlab.ui.control.NumericEditField
        Label_17                        matlab.ui.control.Label
        Label_VPL_Local                 matlab.ui.control.Label
        Spinner_VPL_Local               matlab.ui.control.Spinner
        DropDown_InterpAlg_Auto_Local   matlab.ui.control.DropDown
        Label_16                        matlab.ui.control.Label
        Image_GPU                       matlab.ui.control.Image
        CheckBox_LocalOn                matlab.ui.control.CheckBox
        DropDown_Marker_Local           matlab.ui.control.DropDown
        Label_15                        matlab.ui.control.Label
        DropDown_RegSampling_Local      matlab.ui.control.DropDown
        Label_14                        matlab.ui.control.Label
        Tab_AutoReg_LongTerm            matlab.ui.container.Tab
        TabGroup_LongTerm_Params        matlab.ui.container.TabGroup
        Tab_PointCloud                  matlab.ui.container.Tab
        CheckBox_LongTerm_Review        matlab.ui.control.CheckBox
        EditField_ErrorThreshold        matlab.ui.control.NumericEditField
        Label_41                        matlab.ui.control.Label
        EditField_MaxIterN_LongTerm_PC  matlab.ui.control.NumericEditField
        Label_40                        matlab.ui.control.Label
        EditField_OutlierRatio          matlab.ui.control.NumericEditField
        Label_39                        matlab.ui.control.Label
        EditField_DownSampling_LongTerm_Param  matlab.ui.control.NumericEditField
        Label_38                        matlab.ui.control.Label
        DropDown_DownSampling_LongTerm_PC  matlab.ui.control.DropDown
        Label_37                        matlab.ui.control.Label
        EditField_ScaleThreshold_LongTerm  matlab.ui.control.NumericEditField
        Label_36                        matlab.ui.control.Label
        EditField_IntensityThreshold_LongTerm  matlab.ui.control.NumericEditField
        Label_34                        matlab.ui.control.Label
        Tab_Voxels                      matlab.ui.container.Tab
        EditField_MaxIterN_LongTerm_Voxel  matlab.ui.control.EditField
        Label_50                        matlab.ui.control.Label
        DropDown_PhotonComAcc_LongTerm  matlab.ui.control.DropDown
        Label_49                        matlab.ui.control.Label
        EditField_AFS_LongTerm          matlab.ui.control.NumericEditField
        Label_48                        matlab.ui.control.Label
        CheckBox_PhotonCompOn_LongTerm  matlab.ui.control.CheckBox
        DropDown_DownSampling_LongTerm_Voxel  matlab.ui.control.DropDown
        Label_47                        matlab.ui.control.Label
        EditField_IterCoeff_LongTerm    matlab.ui.control.NumericEditField
        Label_45                        matlab.ui.control.Label
        EditField_InitStep_LongTerm     matlab.ui.control.NumericEditField
        Label_43                        matlab.ui.control.Label
        EditField_MinStep_LongTerm      matlab.ui.control.NumericEditField
        Label_42                        matlab.ui.control.Label
        DropDown_Signal_LongTerm        matlab.ui.control.DropDown
        Label_33                        matlab.ui.control.Label
        DropDown_Marker_LongTerm        matlab.ui.control.DropDown
        Label_32                        matlab.ui.control.Label
        CheckBox_AutoKeyFrame           matlab.ui.control.CheckBox
        EditField_RegTemplate_LongTerm  matlab.ui.control.EditField
        Label_31                        matlab.ui.control.Label
        DropDown_RegSampling_LongTerm   matlab.ui.control.DropDown
        Label_30                        matlab.ui.control.Label
        EditField_KeyFrame              matlab.ui.control.EditField
        Label_29                        matlab.ui.control.Label
        CheckBox_LongTermOn             matlab.ui.control.CheckBox
        Tab_ManualReg                   matlab.ui.container.Tab
        DropDown_TemplateSync           matlab.ui.control.DropDown
        Label_27                        matlab.ui.control.Label
        ButtonGroup_Smooth              matlab.ui.container.ButtonGroup
        Button_Smooth_Manual_N          matlab.ui.control.RadioButton
        Button_Smooth_Manual_Y          matlab.ui.control.RadioButton
        DropDown_InterpAlg_Manual       matlab.ui.control.DropDown
        Label_26                        matlab.ui.control.Label
        Spinner_WPN                     matlab.ui.control.Spinner
        Label_25                        matlab.ui.control.Label
        Spinner_PolyOrder               matlab.ui.control.Spinner
        Label_24                        matlab.ui.control.Label
        EditField_CPNumber              matlab.ui.control.NumericEditField
        Label_23                        matlab.ui.control.Label
        DropDown_RegTform_Manual        matlab.ui.control.DropDown
        Label_22                        matlab.ui.control.Label
    end

    properties (Access = public, Constant)
        VER = "2.0.0"
    end
    
    properties (Access = private)
        caller;         % the parent app
        
        regopts;        % registration option struct
        regalg;         % 1-by-1 string, registration algorithm
        regmode;        % 1-by-1 string, registration mode, "global" or "local"
        fixdef;         % 1-by-2 string, the fixed volume defination
        opt_locked      % 1-by-1 logical, indicate the options locked status
        template_sync;  % the template synchronize mode, "global"/"local"
        hw_info;        % the hardware information struct

        langmap;  % % mapping for language
    end
    
    methods (Access = private)
        function InitData(app, mainapp)
            app.caller = mainapp;
            [regopts_, app.fixdef, app.opt_locked, app.template_sync] = ...
                app.caller.GetRegOptions();
            app.regopts = regopts_.Options;
            app.regalg = regopts_.Algorithm;
            app.regmode = regopts_.Mode;
        end

        function InitPanel(app)
            SetAutoRegGlobalPanel(app);
            SetAutoRegLocalPanel(app);
            % SetAutoRegLongTermPanel(app);
            SetManualRegPanel(app);

            SetCrossOpts(app);
        end

        function SetAutoRegGlobalPanel(app)
            % set registration template
            app.DropDown_RegSampling_Global.Value = upper(app.fixdef(1));

            app.EditField_RegTemplate_Global.Value = app.fixdef(2);

            % set transformation
            app.DropDown_RegTform_Auto.Value = upper(app.regopts.TformType);

            % set the modal
            app.DropDown_RegModal.Value = upper(app.regopts.RegModal);

            % set iteration options
            app.EditField_MinStep.Value = app.regopts.MinStep;
            app.EditField_InitStep.Value = app.regopts.InitStep;
            app.EditField_MaxIterN_Global.Value = app.regopts.MaxIterN;
            app.EditField_IterCoeff.Value = app.regopts.IterCoeff;

             % set downsampling options
            switch app.regopts.DS
                case "auto"
                    app.DropDown_DownSampling.Value = "AUTO";
                case "1X1"
                    app.DropDown_DownSampling.Value = "CLOSE";
                otherwise
                    app.DropDown_DownSampling.Value = app.regopts.DS;
            end

            % set algorithm options
            opts = app.caller.GetImgInfo();
            app.Spinner_VPL_Global.Limits = [1, ...
                floor(log(opts.slices)/log(4))+1];
            app.Spinner_VPL_Global.Value = app.regopts.VPL;

            app.DropDown_InterpAlg_Auto_Global.Value = ...
                upper(app.regopts.Interp);

            app.CheckBox_GlobalLock.Value = app.opt_locked;
        end

        function SetAutoRegLocalPanel(app)
            % set registration template
            app.DropDown_RegSampling_Local.Value = upper(app.fixdef(1));
            
            app.EditField_RegTemplate_Local.Value = app.fixdef(2);

            % set iteration options
            app.EditField_MaxIterN_Local.Value = ...
                num2str(app.regopts.MaxIterN);

            % set algorithm options
            opts = app.caller.GetImgInfo();
            app.EditField_AFS.Value = app.regopts.AFS;
            app.Spinner_VPL_Local.Limits = [1, ...
                floor(log(opts.slices)/log(4))+1];
            app.Spinner_VPL_Local.Value = app.regopts.VPL;
            app.CheckBox_PhotonCompOn.Value = app.regopts.AutoContrast;
            app.DropDown_PhotonComAcc.Value = num2str(app.regopts.ComAcc);
            app.DropDown_PhotonComAcc.Enable = app.regopts.AutoContrast;

            app.DropDown_InterpAlg_Auto_Local.Value = ...
                upper(app.regopts.Interp);

        end

        function SetAutoRegLongTermPanel(app)
            app.EditField_RegTemplate_LongTerm.Value ...
                = app.regopts.volfixed.G(2);
            app.DropDown_RegSampling_LongTerm.Value ...
                = upper(app.regopts.volfixed.G(1));

            if ~isempty(str2num(app.regopts.KeyFrames)) %#ok<ST2NM>
                app.EditField_KeyFrame.Value = app.regopts.KeyFrames;
                app.CheckBox_AutoKeyFrame.Value = false;
            else
                app.EditField_KeyFrame.Enable = "off";
                app.CheckBox_AutoKeyFrame.Value = true;
            end

            app.EditField_IntensityThreshold_LongTerm.Value ...
                = app.regopts.IntensityThreshold;
            app.EditField_ScaleThreshold_LongTerm.Value = ...
                app.regopts.ScaleThreshold;
            app.DropDown_DownSampling_LongTerm_PC.Value = ...
                app.regopts.DS_PointCloud;
            app.EditField_DownSampling_LongTerm_Param.Value = ...
                app.regopts.DS_PointCloud_Param;
            app.EditField_OutlierRatio.Value = ...
                app.regopts.OutlierRatio;
            app.EditField_MaxIterN_LongTerm_PC.Value = ...
                app.regopts.MaxIterN_PointCloud;
            app.EditField_ErrorThreshold.Value = ...
                app.regopts.ErrorLimit;
            app.EditField_MinStep_LongTerm.Value = ...
                app.regopts.MinStep_LongTerm;
            switch app.regopts.DS_Voxel
                case "1x1"
                    app.DropDown_DownSampling_LongTerm_Voxel.Value = "OFF";
                otherwise
                    app.DropDown_DownSampling_LongTerm_Voxel.Value ...
                        = app.regopts.DS_Voxel;
            end
            app.EditField_InitStep_LongTerm.Value = ...
                app.regopts.InitStep_LongTerm;
            app.EditField_IterCoeff_LongTerm.Value = ...
                app.regopts.IterCoeff_LongTerm;
            app.EditField_MaxIterN_LongTerm_Voxel.Value = ...
                string(app.regopts.MaxIterN_Voxel).join(",");
            app.CheckBox_PhotonCompOn_LongTerm.Value = ...
                app.regopts.AutoContrast_LongTerm;
            app.EditField_AFS_LongTerm.Value = ...
                app.regopts.AFS_LongTerm;
            app.DropDown_PhotonComAcc_LongTerm.Value = ...
                string(app.regopts.ComAcc_LongTerm);
        end

        function SetManualRegPanel(app)
            % set the manual registration parameters
            switch app.regopts.ManualTform
                case "polynomial"
                    app.DropDown_RegTform_Manual.Value = "POLYNOMIAL";
                    app.Spinner_PolyOrder.Enable = "on";
                    app.Spinner_WPN.Enable = "off";
                case "lwm"
                    app.DropDown_RegTform_Manual.Value = "LWM";
                    app.Spinner_WPN.Enable = "on";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.caller.GenSysMsg(["LOCAL_WEIGHTED_CONTROL",""]);
                case "nonreflectivesimilarity"
                    app.DropDown_RegTform_Manual.Value = "SIMILARITY";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "affine"
                    app.DropDown_RegTform_Manual.Value = "AFFINE";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "projective"
                    app.DropDown_RegTform_Manual.Value = "PROJECTION";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                case "pwl"
                    app.DropDown_RegTform_Manual.Value = "PWL";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
            end
            app.Spinner_PolyOrder.Value = app.regopts.Degree;
            app.Spinner_WPN.Value = app.regopts.Nlwm;
            UpdateMinCPN(app);

            % set algorithm options
            app.DropDown_InterpAlg_Manual.Value = ...
                upper(app.regopts.ManualInterp);

            if app.regopts.ManualEdgeSmooth == true
                app.ButtonGroup_Smooth.SelectedObject ...
                    = app.Button_Smooth_Manual_Y;
            else
                app.ButtonGroup_Smooth.SelectedObject ...
                    = app.Button_Smooth_Manual_N;
            end

            app.DropDown_TemplateSync.Value = upper(app.template_sync);
        end

        function SetCrossOpts(app)
            % set marker/signal channel
            switch app.regopts.SC
                case "r"
                    app.DropDown_Structured_Global.Value = "RED";
                case "g"
                    app.DropDown_Structured_Global.Value = "GREEN";
            end
            switch app.regopts.FC
                case "r"
                    app.DropDown_Functional_Global.Value = "RED";
                case "g"
                    app.DropDown_Functional_Global.Value = "GREEN";
            end
        end

        function UpdateMinCPN(app)
            switch app.DropDown_RegTform_Manual.Value
                case "SIMILARITY"
                    app.EditField_CPNumber.Value = 2;
                case "AFFINE"
                    app.EditField_CPNumber.Value = 3;
                case "PROJECTION"
                    app.EditField_CPNumber.Value = 4;
                case "PWL"
                    app.EditField_CPNumber.Value = 4;
                case "POLYNOMIAL"
                    switch app.Spinner_PolyOrder.Value
                        case 2
                            app.EditField_CPNumber.Value = 6;
                        case 3
                            app.EditField_CPNumber.Value = 10;
                        case 4
                            app.EditField_CPNumber.Value = 15;
                        otherwise
                    end
                case "LWM"
                    app.EditField_CPNumber.Value = 6;
                otherwise
            end
        end

        function UpdateRegProgram(app)
            if app.CheckBox_GlobalLock.Value == true
                app.regopts.Mode = "global";
            end
            if app.CheckBox_LocalOn.Value == true
                app.regopts.Mode = "local";
            end
            % TODO: longterm
            
        end

        function InitHardwareDetector(app)
            [hw_logo, app.hw_info] = GetHardwareInfo();
            app.Image_CPU.Visible = "on";
            app.Image_GPU.Visible = "on";

            switch hw_logo.cpu
                case "Intel"
                    app.Image_CPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_CPU.ImageSource = "sources/amd_logo.png";
                case "Unknown"
                    app.Image_CPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_CPU.Visible = "off";
            end
            switch hw_logo.gpu
                case "Intel"
                    app.Image_GPU.ImageSource = "sources/intel_logo.png";
                case "AMD"
                    app.Image_GPU.ImageSource = "sources/amd_logo.png";
                case "Nvidia"
                    app.Image_GPU.ImageSource = "sources/nvidia_logo.png";
                case "Unknown"
                    app.Image_GPU.ImageSource = "sources/unknown_logo.png";
                otherwise
                    app.Image_GPU.Visible = "off";
            end
        end
    end
    
    methods (Access = public)
        
        function FontBlink(app,components,ts,c)
            % This function for app components font blink
            arguments
                app (1,1) RegSetting_TC;
                components string {mustBeVector}; % target  source
                ts (:,2) double {mustBePositive} = [0.25,   0.25; ...
                    0.25,   0.25; ...
                    0.25,   0.25]   %time series, seconds
                % c is RGB array
                c (2,3) double {mustBeInRange(c,0,1)} = [1,0,0; ... % target
                    0,0,0];    % source
            end

            N = numel(components);

            for t = 1:size(ts,1)
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(1,:);
                    end
                end
                pause(ts(t,1));
                for k = 1:N
                    if isprop(app.(components(k)),"FontColor")
                        app.(components(k)).FontColor = c(2,:);
                    end
                end
                pause(ts(t,2));
            end
        end

        function LockPanel(app)
            % lock the auto registration (global) panel
            app.EditField_RegTemplate_Global.Enable = "off";
            app.DropDown_RegSampling_Global.Enable = "off";
            app.DropDown_RegTform_Auto.Enable = "off";
            app.DropDown_RegModal.Enable = "off";
            app.DropDown_Structured_Global.Enable = "off";
            app.DropDown_Functional_Global.Enable = "off";
            app.EditField_MinStep.Enable = "off";
            app.EditField_InitStep.Enable = "off";
            app.EditField_MaxIterN_Global.Enable = "off";
            app.EditField_IterCoeff.Enable = "off";
            app.EditField_RobustCoeff.Enable = "off";
            app.DropDown_DownSampling.Enable = "off";
            app.DropDown_InterpAlg_Auto_Global.Enable = "off";
            app.Spinner_VPL_Global.Enable = "off";
            app.CheckBox_GlobalLock.Enable = "off";

            % lock the auto registration (local) panel
            app.EditField_RegTemplate_Local.Enable = "off";
            app.DropDown_RegSampling_Local.Enable = "off";
            app.DropDown_Marker_Local.Enable = "off";
            app.DropDown_Signal_Local.Enable = "off";
            app.EditField_MaxIterN_Local.Enable = "off";
            app.EditField_AFS.Enable = "off";
            app.CheckBox_PhotonCompOn.Enable = "off";
            app.DropDown_PhotonComAcc.Enable = "off";
            app.DropDown_InterpAlg_Auto_Local.Enable = "off";
            app.Spinner_VPL_Local.Enable = "off";
            app.CheckBox_LocalOn.Enable = "off";
            
            % lock the longterm panel
            app.EditField_RegTemplate_LongTerm.Enable = "off";
            app.DropDown_RegSampling_LongTerm.Enable = "off";
            app.CheckBox_AutoKeyFrame.Enable = "off";
            app.EditField_KeyFrame.Enable = "off";
            app.DropDown_Marker_LongTerm.Enable = "off";
            app.DropDown_Signal_LongTerm.Enable = "off";
            app.EditField_IntensityThreshold_LongTerm.Enable = "off";
            app.EditField_ScaleThreshold_LongTerm.Enable = "off";
            app.DropDown_DownSampling_LongTerm_PC.Enable = "off";
            app.EditField_DownSampling_LongTerm_Param.Enable = "off";
            app.EditField_OutlierRatio.Enable = "off";
            app.EditField_MaxIterN_LongTerm_PC.Enable = "off";
            app.EditField_ErrorThreshold.Enable = "off";
            app.CheckBox_LongTerm_Review.Enable = "off";
            app.EditField_MinStep_LongTerm.Enable = "off";
            app.DropDown_DownSampling_LongTerm_Voxel.Enable = "off";
            app.EditField_InitStep_LongTerm.Enable = "off";
            app.EditField_IterCoeff_LongTerm.Enable = "off";
            app.EditField_MaxIterN_LongTerm_Voxel.Enable = "off";
            app.CheckBox_PhotonCompOn_LongTerm.Enable = "off";
            app.EditField_AFS_LongTerm.Enable = "off";
            app.DropDown_PhotonComAcc_LongTerm.Enable = "off";
            app.CheckBox_LongTermOn.Enable = "off";

            % lock the manual registration panel
            app.DropDown_RegTform_Manual.Enable = "off";
            app.EditField_CPNumber.Enable = "off";
            app.Spinner_PolyOrder.Enable = "off";
            app.Spinner_WPN.Enable = "off";
            app.ButtonGroup_Smooth.Enable = "off";
            app.DropDown_InterpAlg_Manual.Enable = "off";
            app.DropDown_TemplateSync.Enable = "off";
        end

        function UnlockPanel(app)
            % unlock the auto registration (global) panel
            app.EditField_RegTemplate_Global.Enable = "on";
            app.DropDown_RegSampling_Global.Enable = "on";
            app.DropDown_RegTform_Auto.Enable = "on";
            app.DropDown_RegModal.Enable = "on";
            app.DropDown_Structured_Global.Enable = "on";
            app.DropDown_Functional_Global.Enable = "on";
            app.EditField_MinStep.Enable = "on";
            app.EditField_InitStep.Enable = "on";
            app.EditField_MaxIterN_Global.Enable = "on";
            app.EditField_IterCoeff.Enable = "on";
            app.EditField_RobustCoeff.Enable = "on";
            app.DropDown_DownSampling.Enable = "on";
            app.DropDown_InterpAlg_Auto_Global.Enable = "on";
            app.Spinner_VPL_Global.Enable = "on";
            app.CheckBox_GlobalLock.Enable = "on";

            % unlock the auto registration (local) panel
            app.EditField_RegTemplate_Local.Enable = "on";
            app.DropDown_RegSampling_Local.Enable = "on";
            app.DropDown_Marker_Local.Enable = "on";
            app.DropDown_Signal_Local.Enable = "on";
            app.EditField_MaxIterN_Local.Enable = "on";
            app.EditField_AFS.Enable = "on";
            app.CheckBox_PhotonCompOn.Enable = "on";
            if app.CheckBox_PhotonCompOn.Value == true
                app.DropDown_PhotonComAcc.Enable = "on";
            end
            app.DropDown_InterpAlg_Auto_Local.Enable = "on";
            app.Spinner_VPL_Local.Enable = "on";
            app.CheckBox_LocalOn.Enable = "on";

            % unlock the longterm panel
            app.EditField_RegTemplate_LongTerm.Enable = "on";
            app.DropDown_RegSampling_LongTerm.Enable = "on";
            app.CheckBox_AutoKeyFrame.Enable = "on";
            if app.CheckBox_AutoKeyFrame.Value == false
                app.EditField_KeyFrame.Enable = "on";
            end
            app.DropDown_Marker_LongTerm.Enable = "on";
            app.DropDown_Signal_LongTerm.Enable = "on";
            app.EditField_IntensityThreshold_LongTerm.Enable = "on";
            app.EditField_ScaleThreshold_LongTerm.Enable = "on";
            app.DropDown_DownSampling_LongTerm_PC.Enable = "on";
            app.EditField_DownSampling_LongTerm_Param.Enable = "on";
            app.EditField_OutlierRatio.Enable = "on";
            app.EditField_MaxIterN_LongTerm_PC.Enable = "on";
            app.EditField_ErrorThreshold.Enable = "on";
            app.CheckBox_LongTerm_Review.Enable = "on";
            app.EditField_MinStep_LongTerm.Enable = "on";
            app.DropDown_DownSampling_LongTerm_Voxel.Enable = "on";
            app.EditField_InitStep_LongTerm.Enable = "on";
            app.EditField_IterCoeff_LongTerm.Enable = "on";
            app.EditField_MaxIterN_LongTerm_Voxel.Enable = "on";
            app.CheckBox_PhotonCompOn_LongTerm.Enable = "on";
            app.EditField_AFS_LongTerm.Enable = "on";
            if app.CheckBox_PhotonCompOn_LongTerm.Value == true
                app.DropDown_PhotonComAcc_LongTerm.Enable = "on";
            end
            app.CheckBox_LongTermOn.Enable = "on";

            % unlock the manual registration panel
            app.DropDown_RegTform_Manual.Enable = "on";
            app.ButtonGroup_Smooth.Enable = "on";
            app.DropDown_InterpAlg_Manual.Enable = "on";
            app.DropDown_TemplateSync.Enable = "on"; 
        end

        function Refresh(app)
            InitData(app);
            InitPanel(app);
        end

        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            app.Tab_Auto_Global.Title = app.langmap.GLOBAL_REGISTRATION_AUTO;
            app.Tab_Auto_Local.Title = app.langmap.LOCAL_REGISTRATION_AUTO;
            app.Tab_AutoReg_LongTerm.Title = app.langmap.LONGTERM_REGISTRATION_AUTO;
            app.Tab_ManualReg.Title = app.langmap.GLOBAL_REGISTRATION_MANUAL;
            app.Tab_PointCloud.Title = app.langmap.POINT_CLOUD;
            app.Tab_Voxels.Title = app.langmap.VOXEL;
            app.Label.Text = app.langmap.SAMPLING;
            app.Label_2.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_3.Text = app.langmap.LOCATION;
            app.Label_4.Text = app.langmap.SIGNAL;
            app.Label_5.Text = app.langmap.MIN_ITERATION_STEP;
            app.Label_6.Text = app.langmap.INITIAL_ITERATION_STEP;
            app.Label_7.Text = app.langmap.MAX_ITERATION_N;
            app.Label_8.Text = app.langmap.ITERATION_COEFFICIENT;
            app.Label_9.Text = app.langmap.INTERPOLATION;
            app.Label_10.Text = app.langmap.MODAL;
            app.Label_VPL_Global.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_11.Text = app.langmap.REGISTRATION_TEMPLATE;
            app.Label_12.Text = app.langmap.ROBUSTNESS_COEFFICIENT;
            app.Label_13.Text = app.langmap.DOWNSAMPLING;
            app.Label_14.Text = app.langmap.SAMPLING;
            app.Label_15.Text = app.langmap.LOCATION;
            app.Label_16.Text = app.langmap.INTERPOLATION;
            app.Label_17.Text = app.langmap.ACCUMULATIVE_FIELD_SMOOTHING;
            app.Label_18.Text = app.langmap.SIGNAL;
            app.Label_19.Text = app.langmap.PRECISION;
            app.Label_20.Text = app.langmap.MAX_ITERATION_N;
            app.Label_21.Text = app.langmap.REGISTRATION_TEMPLATE;
            app.Label_22.Text = app.langmap.GEOMETRIC_TRANSFORMATION;
            app.Label_23.Text = app.langmap.MIN_CONTROL_VAL;
            app.Label_VPL_Local.Text = app.langmap.PYRAMID_COEFFICIENT;
            app.Label_24.Text = app.langmap.POLYNOMIAL_ORDER;
            app.Label_25.Text = app.langmap.WEIGHTED_POINTS_NUMBER;
            app.Label_26.Text = app.langmap.INTERPOLATION;
            app.Label_27.Text = app.langmap.TEMPLATE_SYNCHRONISATION;
            app.Label_29.Text = app.langmap.KEY_FRAME;
            app.Label_30.Text = app.langmap.SAMPLING;
            app.Label_31.Text = app.langmap.REGISTRATION_TEMPLATE;
            app.Label_32.Text = app.langmap.LOCATION;
            app.Label_33.Text = app.langmap.SIGNAL;
            app.Label_34.Text = app.langmap.INTENSITY_THRESHOLD;
            app.Label_36.Text = app.langmap.SCALE_THRESHOLD;
            app.Label_37.Text = app.langmap.DOWNSAMPLING;
            app.Label_38.Text = app.langmap.GRID_STEP;
            app.Label_39.Text = app.langmap.OUTLIER_RATIO;
            app.Label_40.Text = app.langmap.MAX_ITERATION_N;
            app.Label_41.Text = app.langmap.GLOBAL_ERROR_LIMIT;
            app.Label_42.Text = app.langmap.MIN_ITERATION_STEP;
            app.Label_43.Text = app.langmap.INITIAL_ITERATION_STEP;
            app.Label_45.Text = app.langmap.ITERATION_COEFFICIENT;
            app.Label_47.Text = app.langmap.DOWNSAMPLING;
            app.Label_48.Text = app.langmap.ACCUMULATIVE_FIELD_SMOOTHING;
            app.Label_49.Text = app.langmap.PRECISION;
            app.Label_50.Text = app.langmap.MAX_ITERATION_N;
            
            app.DropDown_RegSampling_Global.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];
            app.DropDown_RegTform_Auto.Items = [app.langmap.TRANSLATION,...
                app.langmap.RIGID, app.langmap.SIMILARITY, ...
                app.langmap.AFFINE];
            app.DropDown_RegModal.Items = [app.langmap.MONOMODAL, ...
                app.langmap.MULTIMODAL];
            app.DropDown_Structured_Global.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_Functional_Global.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_DownSampling.Items = [app.langmap.AUTO, ...
                "2X2", "3X3", app.langmap.CLOSE];
            app.DropDown_InterpAlg_Auto_Global.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];
            app.DropDown_RegSampling_Local.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];
            app.DropDown_RegSampling_LongTerm.Items = [app.langmap.MEAN,...
                app.langmap.MEDIAN, app.langmap.MIN, app.langmap.MAX];
            app.DropDown_Marker_Local.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_Signal_Local.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_Marker_LongTerm.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_Signal_LongTerm.Items = [app.langmap.RED, ...
                app.langmap.GREEN];
            app.DropDown_DownSampling_LongTerm_PC.Items = [app.langmap.GRID_AVERAGE, ...
                app.langmap.RANDOM_UNIFORM, app.langmap.NONUNIFORM];
            app.DropDown_InterpAlg_Auto_Local.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];
            app.DropDown_RegTform_Manual.Items = [app.langmap.SIMILARITY,...
                app.langmap.AFFINE, app.langmap.PROJECTION, ...
                app.langmap.PIECEWISE_LINEAR, app.langmap.POLYNOMIAL,...
                app.langmap.LOCAL_WEIGHTED_MEAN];
            app.DropDown_InterpAlg_Manual.Items = [app.langmap.LINEAR_FAST, ...
                app.langmap.CUBIC_HIGH_QUALITY];
            app.DropDown_TemplateSync.Items = [app.langmap.GLOBAL_REGISTRATION_AUTO, ...
                app.langmap.LOCAL_REGISTRATION_AUTO];
            app.CheckBox_GlobalLock.Text = app.langmap.ENABLE;
            app.CheckBox_LocalOn.Text = app.langmap.ENABLE;
            app.CheckBox_AutoKeyFrame.Text = app.langmap.AUTO;
            app.CheckBox_LongTerm_Review.Text = app.langmap.PREVIEW;
            app.CheckBox_LongTermOn.Text = app.langmap.ENABLE;
            app.CheckBox_PhotonCompOn.Text = app.langmap.COMPENSATION_PHOTOBLEACHING;
            app.CheckBox_PhotonCompOn_LongTerm.Text = app.langmap.COMPENSATION_PHOTOBLEACHING;
            app.ButtonGroup_Smooth.Title = app.langmap.EDGE_SMOOTHING;
            app.Button_Smooth_Manual_Y.Text = app.langmap.YES;
            app.Button_Smooth_Manual_N.Text = app.langmap.NO;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            InitData(app, mainapp);

            InitPanel(app);

            InitHardwareDetector(app);

            RefreshPanelLanguage(app);
        end

        % Value changed function: EditField_RegTemplate_Global
        function EditField_RegTemplate_GlobalValueChanged(app, event)
            value = app.EditField_RegTemplate_Global.Value;
            opts = app.caller.GetImgInfo();
            value = string(value).replace("end",num2str(opts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || numel(value) > opts.frames
                % blink the field
                FontBlink(app,"EditField_RegTemplate_Global");
                app.EditField_RegTemplate_Global.Value = ...
                    app.regopts.volfixed.G(2).join(",");
            else
                app.regopts.volfixed.G(2) = value;
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_RegSampling_Global
        function DropDown_RegSampling_GlobalValueChanged(app, event)
            value = app.DropDown_RegSampling_Global.Value;

            app.regopts.volfixed.G(1) = lower(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_RegTform_Auto
        function DropDown_RegTform_AutoValueChanged(app, event)
            value = app.DropDown_RegTform_Auto.Value;

            switch value
                case "TRANSLATION"
                    app.DropDown_RegTform_Auto.Tooltip = ...
                        app.langmap.TRANSLATION_TOOLTIP;
                case "RIGID"
                    app.DropDown_RegTform_Auto.Tooltip = ...
                        app.langmap.RIGID_TOOLTIP;
                case "SIMILARITY"
                    app.DropDown_RegTform_Auto.Tooltip = ...
                        app.langmap.SIMILARITY_TOOLTIP;
                case "AFFINE"
                    app.DropDown_RegTform_Auto.Tooltip = ...
                        app.langmap.AFFINE_TOOLTIP;
                otherwise
            end
            
            app.regopts.AutoTform = lower(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_RegModal
        function DropDown_RegModalValueChanged(app, event)
            value = app.DropDown_RegModal.Value;
            
            app.regopts.RegModal = lower(value);

            switch value
                case "MONOMODAL"
                    app.EditField_InitStep.Value = 0.1;
                    app.regopts.InitStep = 0.1;
                    app.EditField_IterCoeff.Value = 0.5;
                    app.regopts.IterCoeff = 0.5;
                case "MULTIMODAL"
                    app.EditField_InitStep.Value = 1e-2;
                    app.regopts.InitStep = 1e-2;
                    app.EditField_IterCoeff.Value = 1.05;
                    app.regopts.IterCoeff = 1.05;
                otherwise
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_Structured_Global
        function DropDown_Structured_GlobalValueChanged(app, event)
            value = app.DropDown_Structured_Global.Value;

            switch value
                case "RED"
                    app.regopts.Chla = "r";
                case "GREEN"
                    app.regopts.Chla = "g";
                otherwise
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_Functional_Global
        function DropDown_Functional_GlobalValueChanged(app, event)
            value = app.DropDown_Functional_Global.Value;
            
            switch value
                case "RED"
                    app.regopts.Chls = "r";
                case "GREEN"
                    app.regopts.Chls = "g";
                otherwise
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_MinStep
        function EditField_MinStepValueChanged(app, event)
            value = app.EditField_MinStep.Value;
            
            app.regopts.MinStep = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_InitStep
        function EditField_InitStepValueChanged(app, event)
            value = app.EditField_InitStep.Value;
            
            app.regopts.InitStep = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Image clicked function: Image_CPU
        function Image_CPUClicked(app, event)
            uialert(app.RSTCUIFigure,app.hw_info.cpu, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: EditField_MaxIterN_Global
        function EditField_MaxIterN_GlobalValueChanged(app, event)
            value = app.EditField_MaxIterN_Global.Value;
            
            app.regopts.MaxIterN_Rigid = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_IterCoeff
        function EditField_IterCoeffValueChanged(app, event)
            value = app.EditField_IterCoeff.Value;
            
            app.regopts.IterCoeff = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Callback function: not associated with a component
        function EditField_RobustCoeffValueChanged(app, event)
            value = app.EditField_RobustCoeff.Value;
            
            app.regopts.CoRegC = (1 - sqrt(value)) * 100;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_DownSampling
        function DropDown_DownSamplingValueChanged(app, event)
            value = app.DropDown_DownSampling.Value;
            
            switch value
                case "AUTO"
                    app.regopts.DS = "auto";
                case "CLOSE"
                    app.regopts.DS = "1X1";
                otherwise
                    app.regopts.DS = string(value);
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_InterpAlg_Auto_Global
        function DropDown_InterpAlg_Auto_GlobalValueChanged(app, event)
            value = app.DropDown_InterpAlg_Auto_Global.Value;
            
            app.regopts.Interp_Rigid = lower(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: Spinner_VPL_Global
        function Spinner_VPL_GlobalValueChanged(app, event)
            value = app.Spinner_VPL_Global.Value;
            
            app.regopts.VPL_Rigid = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: CheckBox_GlobalLock
        function CheckBox_GlobalLockValueChanged(app, event)
            value = app.CheckBox_GlobalLock.Value;

            if value == true
                if app.CheckBox_LongTermOn.Value == true
                    slt = uiconfirm(app.RSTCUIFigure, ...
                        app.langmap.NORMAL_LONG_TERM_CONFLICT, ...
                        app.langmap.WARNING, ...
                        "Icon","warning");
                    if slt == "OK"
                        app.CheckBox_LongTermOn.Value = false;
                    else
                        app.CheckBox_GlobalLock.Value = false;
                    end
                end
            end
            
            UpdateRegProgram(app);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_RegTemplate_Local
        function EditField_RegTemplate_LocalValueChanged(app, event)
            value = app.EditField_RegTemplate_Local.Value;
            opts = app.caller.GetImgInfo();
            value = string(value).replace("end",num2str(opts.frames));
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || numel(value) > opts.frames
                % blink the field
                FontBlink(app,"EditField_RegTemplate_Local");
                app.EditField_RegTemplate_Local.Value = ...
                    app.regopts.volfixed.L(2).join(",");
            else
                app.regopts.volfixed.L(2) = value;
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_RegSampling_Local
        function DropDown_RegSampling_LocalValueChanged(app, event)
            value = app.DropDown_RegSampling_Local.Value;
            
            app.regopts.volfixed.L(1) = lower(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_Marker_Local
        function DropDown_Marker_LocalValueChanged(app, event)
            value = app.DropDown_Marker_Local.Value;
            
            switch value
                case "RED"
                    app.regopts.Chla = "r";
                case "GREEN"
                    app.regopts.Chla = "g";
                otherwise
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_Signal_Local
        function DropDown_Signal_LocalValueChanged(app, event)
            value = app.DropDown_Signal_Local.Value;
            
            switch value
                case "RED"
                    app.regopts.Chls = "r";
                case "GREEN"
                    app.regopts.Chls = "g";
                otherwise
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_MaxIterN_Local
        function EditField_MaxIterN_LocalValueChanged(app, event)
            value = app.EditField_MaxIterN_Local.Value;
            
            value = str2num(value); %#ok<ST2NM>
            if isempty(value) || numel(value)>app.regopts.VPL_NonRigid
                % blink the field
                FontBlink(app,"EditField_MaxIterN_Local");
                app.EditField_MaxIterN_Local.Value = ...
                    string(app.regopts.MaxIterN_NonRigid).join(",");
            else
                app.regopts.MaxIterN_NonRigid = value;
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_AFS
        function EditField_AFSValueChanged(app, event)
            value = app.EditField_AFS.Value;
            
            app.regopts.AFS = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Image clicked function: Image_GPU
        function Image_GPUClicked(app, event)
            uialert(app.RSTCUIFigure,app.hw_info.gpu, ...
                app.langmap.INFORMATION, "Icon","info");
        end

        % Value changed function: CheckBox_PhotonCompOn
        function CheckBox_PhotonCompOnValueChanged(app, event)
            value = app.CheckBox_PhotonCompOn.Value;
            
            if value == true
                app.DropDown_PhotonComAcc.Enable = true;
                app.regopts.AutoContrast = true;
            else
                app.DropDown_PhotonComAcc.Enable = false;
                app.regopts.AutoContrast = false;
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_PhotonComAcc
        function DropDown_PhotonComAccValueChanged(app, event)
            value = app.DropDown_PhotonComAcc.Value;
            
            app.regopts.ComAcc = str2double(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_InterpAlg_Auto_Local
        function DropDown_InterpAlg_Auto_LocalValueChanged(app, event)
            value = app.DropDown_InterpAlg_Auto_Local.Value;
            
            app.regopts.Interp_NonRigid = lower(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: Spinner_VPL_Local
        function Spinner_VPL_LocalValueChanged(app, event)
            value = app.Spinner_VPL_Local.Value;
            
            app.regopts.VPL_NonRigid = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: CheckBox_LocalOn
        function CheckBox_LocalOnValueChanged(app, event)
            value = app.CheckBox_LocalOn.Value;

            if value == true
                if app.CheckBox_LongTermOn.Value == true
                    slt = uiconfirm(app.RSTCUIFigure, ...
                        app.langmap.NORMAL_LONG_TERM_CONFLICT, ...
                        app.langmap.WARNING, ...
                        "Icon","warning");
                    if slt == "OK"
                        app.CheckBox_LongTermOn.Value = false;
                    else
                        app.CheckBox_LocalOn.Value = false;
                    end
                end
            end

            UpdateRegProgram(app);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_RegTform_Manual
        function DropDown_RegTform_ManualValueChanged(app, event)
            value = app.DropDown_RegTform_Manual.Value;
            
            switch value
                case "POLYNOMIAL"
                    app.regopts.ManualTform = "polynomial";
                    app.Spinner_PolyOrder.Enable = "on";
                    app.Spinner_WPN.Enable = "off";
                case "LWM"
                    app.regopts.ManualTform = "lwm";
                    app.Spinner_WPN.Enable = "on";
                    app.Spinner_PolyOrder.Enable = "off";
                    app.caller.GenSysMsg(["LOCAL_WEIGHTED_CONTROL",""]);
                otherwise
                    app.Spinner_PolyOrder.Enable = "off";
                    app.Spinner_WPN.Enable = "off";
                    switch value
                        case "SIMILARITY"
                            app.regopts.ManualTform = "nonreflectivesimilarity";
                        case "AFFINE"
                            app.regopts.ManualTform = "affine";
                        case "PROJECTION"
                            app.regopts.ManualTform = "projective";
                        case "PWL"
                            app.regopts.ManualTform = "pwl";
                        otherwise
                    end
            end

            UpdateMinCPN(app);
            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: Spinner_PolyOrder
        function Spinner_PolyOrderValueChanged(app, event)
            value = app.Spinner_PolyOrder.Value;
            app.regopts.Degree = value;

            UpdateMinCPN(app);
            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: Spinner_WPN
        function Spinner_WPNValueChanged(app, event)
            value = app.Spinner_WPN.Value;
            app.regopts.Nlwm = value;

            UpdateMinCPN(app);
            app.caller.SetRegParams(app.regopts);
        end

        % Selection changed function: ButtonGroup_Smooth
        function ButtonGroup_SmoothSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Smooth.SelectedObject;
            
            switch selectedButton
                case app.Button_Smooth_Manual_Y
                    app.regopts.ManualEdgeSmooth = true;
                case app.Button_Smooth_Manual_N
                    app.regopts.ManualEdgeSmooth = false;
                otherwise
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_InterpAlg_Manual
        function DropDown_InterpAlg_ManualValueChanged(app, event)
            value = app.DropDown_InterpAlg_Manual.Value;
            
            app.regopts.ManualInterp = lower(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_TemplateSync
        function DropDown_TemplateSyncValueChanged(app, event)
            value = app.DropDown_TemplateSync.Value;
            
            app.template_sync = lower(value);

            app.caller.SetRegParams(app.regopts, app.template_sync);
        end

        % Close request function: RSTCUIFigure
        function RSTCUIFigureCloseRequest(app, event)
            % Set the registration options
            app.caller.SetRegParams(app.regopts, app.template_sync);

            delete(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            switch selectedTab
                case app.Tab_ManualReg
                    if app.regopts.RegType ~= "manual"
                       app.caller.GenSysMsg(["REGMODE_CURRENT_MANUAL",""]);
                    end
                    app.regopts.RegType = "manual";
                    app.caller.SetRegParams(app.regopts);
                case app.Tab_AutoReg_LongTerm
                    if app.regopts.RegType ~= "longterm"
                       app.caller.GenSysMsg(["REGMODE_CURRENT_LONGTERM",""]);
                    end
                    app.regopts.RegType = "longterm";
                    app.caller.SetRegParams(app.regopts);
                otherwise
                    if app.regopts.RegType ~= "auto"
                        app.caller.GenSysMsg(["REGMODE_CURRENT_AUTO",""]);
                    end
                    app.regopts.RegType = "auto";
                    app.caller.SetRegParams(app.regopts);
            end 
        end

        % Value changed function: EditField_RegTemplate_LongTerm
        function EditField_RegTemplate_LongTermValueChanged(app, event)
            value = app.EditField_RegTemplate_LongTerm.Value;
            
            app.regopts.volfixed.G(2) = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_RegSampling_LongTerm
        function DropDown_RegSampling_LongTermValueChanged(app, event)
            value = app.DropDown_RegSampling_LongTerm.Value;
            
            app.regopts.volfixed.G(1) = lower(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_KeyFrame
        function EditField_KeyFrameValueChanged(app, event)
            value = app.EditField_KeyFrame.Value;
            
            app.regopts.KeyFrames = string(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: CheckBox_AutoKeyFrame
        function CheckBox_AutoKeyFrameValueChanged(app, event)
            value = app.CheckBox_AutoKeyFrame.Value;
            
            if value == true
                app.EditField_KeyFrame.Enable = "off";
                app.regopts.KeyFrames = "auto";
            else
                app.EditField_KeyFrame.Enable = "on";
                app.regopts.KeyFrames = string(app.EditField_KeyFrame.Value);
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_Marker_LongTerm
        function DropDown_Marker_LongTermValueChanged(app, event)
            value = app.DropDown_Marker_LongTerm.Value;
            
            switch value
                case "RED"
                    app.regopts.Chla = "r";
                case "GREEN"
                    app.regopts.Chla = "g";
                otherwise
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_Signal_LongTerm
        function DropDown_Signal_LongTermValueChanged(app, event)
            value = app.DropDown_Signal_LongTerm.Value;
            
            switch value
                case "RED"
                    app.regopts.Chls = "r";
                case "GREEN"
                    app.regopts.Chls = "g";
                otherwise
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_IntensityThreshold_LongTerm
        function EditField_IntensityThreshold_LongTermValueChanged(app, event)
            value = app.EditField_IntensityThreshold_LongTerm.Value;
            
            app.regopts.IntensityThreshold = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_ScaleThreshold_LongTerm
        function EditField_ScaleThreshold_LongTermValueChanged(app, event)
            value = app.EditField_ScaleThreshold_LongTerm.Value;
            
            app.regopts.ScaleThreshold = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_DownSampling_LongTerm_PC
        function DropDown_DownSampling_LongTerm_PCValueChanged(app, event)
            value = app.DropDown_DownSampling_LongTerm_PC.Value;
            
            switch value
                case "GA"
                    app.Label_38.Text = "网格步长";
                case "RAND"
                    app.Label_38.Text = "百分比";
                case "NU"
                    app.Label_38.Text = "最大数量";
                otherwise
                    app.Label_38.Text = "参数值";
            end

            app.regopts.DS_PointCloud = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_DownSampling_LongTerm_Param
        function EditField_DownSampling_LongTerm_ParamValueChanged(app, event)
            value = app.EditField_DownSampling_LongTerm_Param.Value;
            
            app.regopts.DS_PointCloud_Param = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_OutlierRatio
        function EditField_OutlierRatioValueChanged(app, event)
            value = app.EditField_OutlierRatio.Value;
            
            app.regopts.OutlierRatio = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_MaxIterN_LongTerm_PC
        function EditField_MaxIterN_LongTerm_PCValueChanged(app, event)
            value = app.EditField_MaxIterN_LongTerm_PC.Value;
            
            app.regopts.MaxIterN_PointCloud = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_ErrorThreshold
        function EditField_ErrorThresholdValueChanged(app, event)
            value = app.EditField_ErrorThreshold.Value;
            app.regopts.ErrorLimit = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: CheckBox_LongTerm_Review
        function CheckBox_LongTerm_ReviewValueChanged(app, event)
            value = app.CheckBox_LongTerm_Review.Value;
            
            if value == true
                % append points cloud registration preview tab

            else
                % remove points cloud registration preview tab

            end
        end

        % Value changed function: EditField_MinStep_LongTerm
        function EditField_MinStep_LongTermValueChanged(app, event)
            value = app.EditField_MinStep_LongTerm.Value;
            app.regopts.app.reg_opts.MinStep_LongTerm = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_DownSampling_LongTerm_Voxel
        function DropDown_DownSampling_LongTerm_VoxelValueChanged(app, event)
            value = app.DropDown_DownSampling_LongTerm_Voxel.Value;
            switch value
                case "OFF"
                    app.regopts.DS_Voxel = "1x1";
                otherwise
                    app.regopts.DS_Voxel = value;
            end

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_InitStep_LongTerm
        function EditField_InitStep_LongTermValueChanged(app, event)
            value = app.EditField_InitStep_LongTerm.Value;
            app.regopts.InitStep_LongTerm = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_IterCoeff_LongTerm
        function EditField_IterCoeff_LongTermValueChanged(app, event)
            value = app.EditField_IterCoeff_LongTerm.Value;
            app.regopts.IterCoeff_LongTerm = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: EditField_MaxIterN_LongTerm_Voxel
        function EditField_MaxIterN_LongTerm_VoxelValueChanged(app, event)
            value = app.EditField_MaxIterN_LongTerm_Voxel.Value;
            app.regopts.MaxIterN_Voxel = str2num(value); %#ok<ST2NM>

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: CheckBox_PhotonCompOn_LongTerm
        function CheckBox_PhotonCompOn_LongTermValueChanged(app, event)
            value = app.CheckBox_PhotonCompOn_LongTerm.Value;
            
            if value == true
                app.DropDown_PhotonComAcc_LongTerm.Enable = "on";
            else
                app.DropDown_PhotonComAcc_LongTerm.Enable = "off";
            end
        end

        % Value changed function: EditField_AFS_LongTerm
        function EditField_AFS_LongTermValueChanged(app, event)
            value = app.EditField_AFS_LongTerm.Value;
            app.regopts.AFS_LongTerm = value;

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: DropDown_PhotonComAcc_LongTerm
        function DropDown_PhotonComAcc_LongTermValueChanged(app, event)
            value = app.DropDown_PhotonComAcc_LongTerm.Value;
            app.regopts.ComAcc_LongTerm = str2double(value);

            app.caller.SetRegParams(app.regopts);
        end

        % Value changed function: CheckBox_LongTermOn
        function CheckBox_LongTermOnValueChanged(app, event)
            value = app.CheckBox_LongTermOn.Value;
            
            if value == true
                if app.CheckBox_GlobalLock.Value || app.CheckBox_LocalOn.Value
                    slt = uiconfirm(app.RSTCUIFigure, ...
                        app.langmap.LONG_TERM_NORMAL_CONFLICT, ...
                        app.langmap.WARNING, ...
                        "Icon","warning");
                    if slt == "OK"
                        app.CheckBox_GlobalLock.Value = false;
                        app.CheckBox_LocalOn.Value = false;
                    else
                        app.CheckBox_LongTermOn.Value = false;
                    end
                end
            end

            UpdateRegProgram(app);
            app.caller.SetRegParams(app.regopts);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RSTCUIFigure = uifigure('Visible', 'off');
            app.RSTCUIFigure.Name = 'RSTC';
            app.RSTCUIFigure.Icon = 'rstc.png';
            app.RSTCUIFigure.CloseRequestFcn = createCallbackFcn(app, @RSTCUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.RSTCUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_Auto_Global = uitab(app.TabGroup);
            app.Tab_Auto_Global.Tooltip = {'基于相位及强度的全自动刚体配准方案'};
            app.Tab_Auto_Global.Title = '自动配准(全局)';
            app.GridLayout = uigridlayout(app.Tab_Auto_Global);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CheckBox_GlobalLock = uicheckbox(app.GridLayout2);
            app.CheckBox_GlobalLock.ValueChangedFcn = createCallbackFcn(app, @CheckBox_GlobalLockValueChanged, true);
            app.CheckBox_GlobalLock.Tooltip = {'启用刚体配准'};
            app.CheckBox_GlobalLock.Text = '锁定';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.Tab_Auto_NS_Global = uitab(app.TabGroup2);
            app.Tab_Auto_NS_Global.Title = '常用';
            app.GridLayout10 = uigridlayout(app.Tab_Auto_NS_Global);
            app.Panel_NS_Reg = uipanel(app.GridLayout10);
            app.Panel_NS_Reg.Title = '配准';
            app.GridLayout9 = uigridlayout(app.Panel_NS_Reg);
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.Label_11 = uilabel(app.GridLayout11);
            app.Label_11.Text = '配准模板';
            app.EditField_RegTemplate_Global = uieditfield(app.GridLayout11, 'text');
            app.EditField_RegTemplate_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_GlobalValueChanged, true);
            app.EditField_RegTemplate_Global.Placeholder = '1';
            app.CheckBox_AutoTemplate = uicheckbox(app.GridLayout11);
            app.CheckBox_AutoTemplate.Text = '自动';
            app.GridLayout12 = uigridlayout(app.GridLayout9);
            app.Label = uilabel(app.GridLayout12);
            app.Label.Text = '采样';
            app.DropDown_RegSampling_Global = uidropdown(app.GridLayout12);
            app.DropDown_RegSampling_Global.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_Global.ItemsData = {'MEAN', 'MEDIAN', 'MIN', 'MAX'};
            app.DropDown_RegSampling_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_GlobalValueChanged, true);
            app.DropDown_RegSampling_Global.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_Global.Value = 'MEAN';
            app.Label_2 = uilabel(app.GridLayout12);
            app.Label_2.Text = '几何变换';
            app.DropDown_RegTform_Auto = uidropdown(app.GridLayout12);
            app.DropDown_RegTform_Auto.Items = {'平移', '刚体', '仿射'};
            app.DropDown_RegTform_Auto.ItemsData = {'TRANSLATION', 'RIGID', 'AFFINE'};
            app.DropDown_RegTform_Auto.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_AutoValueChanged, true);
            app.DropDown_RegTform_Auto.Tooltip = {'几何变换类型'};
            app.DropDown_RegTform_Auto.Value = 'TRANSLATION';
            app.GridLayout13 = uigridlayout(app.GridLayout9);
            app.Label_3 = uilabel(app.GridLayout13);
            app.Label_3.Text = '定位';
            app.DropDown_Structured_Global = uidropdown(app.GridLayout13);
            app.DropDown_Structured_Global.Items = {'红', '绿'};
            app.DropDown_Structured_Global.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Structured_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Structured_GlobalValueChanged, true);
            app.DropDown_Structured_Global.Tooltip = {''};
            app.DropDown_Structured_Global.Value = 'RED';
            app.Label_4 = uilabel(app.GridLayout13);
            app.Label_4.Text = '信号';
            app.DropDown_Functional_Global = uidropdown(app.GridLayout13);
            app.DropDown_Functional_Global.Items = {'红', '绿'};
            app.DropDown_Functional_Global.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Functional_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_Functional_GlobalValueChanged, true);
            app.DropDown_Functional_Global.Tooltip = {''};
            app.DropDown_Functional_Global.Value = 'GREEN';
            app.GridLayout14 = uigridlayout(app.GridLayout9);
            app.Image_CPU = uiimage(app.GridLayout14);
            app.Image_CPU.ImageClickedFcn = createCallbackFcn(app, @Image_CPUClicked, true);
            app.Image_CPU.Visible = 'off';
            app.Image_CPU.Tooltip = {'CPU状态'};
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.Label_5 = uilabel(app.GridLayout15);
            app.Label_5.Text = '最小迭代步长';
            app.EditField_MinStep = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_MinStep.Limits = [0 1];
            app.EditField_MinStep.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStepValueChanged, true);
            app.EditField_MinStep.Tooltip = {'收敛判别步长'};
            app.EditField_MinStep.Value = 0.0001;
            app.Label_6 = uilabel(app.GridLayout15);
            app.Label_6.Text = '初始迭代步长';
            app.EditField_InitStep = uieditfield(app.GridLayout15, 'numeric');
            app.EditField_InitStep.Limits = [0 1];
            app.EditField_InitStep.ValueChangedFcn = createCallbackFcn(app, @EditField_InitStepValueChanged, true);
            app.EditField_InitStep.Tooltip = {'起始步长'};
            app.EditField_InitStep.Value = 0.1;
            app.Tab_Auto_AS_Global = uitab(app.TabGroup2);
            app.Tab_Auto_AS_Global.Title = '高级';
            app.GridLayout3 = uigridlayout(app.Tab_Auto_AS_Global);
            app.Panel = uipanel(app.GridLayout3);
            app.Panel.Title = '预处理';
            app.GridLayout4 = uigridlayout(app.Panel);
            app.Label_53 = uilabel(app.GridLayout4);
            app.Label_53.Text = '开运算';
            app.EditField_OpenOperatorSize = uieditfield(app.GridLayout4, 'text');
            app.EditField_OpenOperatorSize.Placeholder = '5,5,2';
            app.Label_51 = uilabel(app.GridLayout4);
            app.Label_51.Text = '中值滤波器';
            app.EditField_MedianFilterSize = uieditfield(app.GridLayout4, 'text');
            app.EditField_MedianFilterSize.Placeholder = '3,3,3';
            app.Label_55 = uilabel(app.GridLayout4);
            app.Label_55.Text = '迭代数上限';
            app.EditField_OptimalZ_MaxIterN = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_OptimalZ_MaxIterN.Limits = [0 Inf];
            app.EditField_OptimalZ_MaxIterN.ValueDisplayFormat = '%.0f';
            app.EditField_OptimalZ_MaxIterN.Value = 100;
            app.Label_56 = uilabel(app.GridLayout4);
            app.Label_56.Text = '公差';
            app.EditField_OptimalZ_Tol = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_OptimalZ_Tol.Limits = [0 1];
            app.EditField_OptimalZ_Tol.Value = 0.001;
            app.Label_52 = uilabel(app.GridLayout4);
            app.Label_52.Text = '高斯滤波器';
            app.EditField_GaussianFilterSize = uieditfield(app.GridLayout4, 'text');
            app.EditField_GaussianFilterSize.Placeholder = '3,3,1';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.Panel_2.Title = '配准';
            app.GridLayout5 = uigridlayout(app.Panel_2);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Label_13 = uilabel(app.GridLayout6);
            app.Label_13.Text = '降采样';
            app.DropDown_DownSampling = uidropdown(app.GridLayout6);
            app.DropDown_DownSampling.Items = {'自动', '2X2', '3X3', '关闭'};
            app.DropDown_DownSampling.ItemsData = {'AUTO', '2X2', '3X3', 'CLOSE'};
            app.DropDown_DownSampling.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSamplingValueChanged, true);
            app.DropDown_DownSampling.Tooltip = {'加速配准并降低资源占用，但会降低配准精度'};
            app.DropDown_DownSampling.Value = 'AUTO';
            app.Label_10 = uilabel(app.GridLayout6);
            app.Label_10.Text = '模态';
            app.DropDown_RegModal = uidropdown(app.GridLayout6);
            app.DropDown_RegModal.Items = {'单模态', '多模态'};
            app.DropDown_RegModal.ItemsData = {'MONOMODAL', 'MULTIMODAL'};
            app.DropDown_RegModal.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegModalValueChanged, true);
            app.DropDown_RegModal.Tooltip = {'采集设备属性'};
            app.DropDown_RegModal.Value = 'MONOMODAL';
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.Label_7 = uilabel(app.GridLayout7);
            app.Label_7.Text = '迭代数上限';
            app.EditField_MaxIterN_Global = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_MaxIterN_Global.Limits = [1 Inf];
            app.EditField_MaxIterN_Global.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_Global.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_GlobalValueChanged, true);
            app.EditField_MaxIterN_Global.Tooltip = {'最大迭代次数'};
            app.EditField_MaxIterN_Global.Value = 50;
            app.Label_8 = uilabel(app.GridLayout7);
            app.Label_8.Text = '迭代因子';
            app.EditField_IterCoeff = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_IterCoeff.Limits = [0 1];
            app.EditField_IterCoeff.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeffValueChanged, true);
            app.EditField_IterCoeff.Tooltip = {''};
            app.EditField_IterCoeff.Value = 0.5;
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.Label_9 = uilabel(app.GridLayout8);
            app.Label_9.Text = '插值';
            app.DropDown_InterpAlg_Auto_Global = uidropdown(app.GridLayout8);
            app.DropDown_InterpAlg_Auto_Global.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_InterpAlg_Auto_Global.ItemsData = {'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Auto_Global.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_Auto_GlobalValueChanged, true);
            app.DropDown_InterpAlg_Auto_Global.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Auto_Global.Value = 'LINEAR';
            app.Spinner_VPL_Global = uispinner(app.GridLayout8);
            app.Spinner_VPL_Global.Limits = [2 4];
            app.Spinner_VPL_Global.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Global.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_GlobalValueChanged, true);
            app.Spinner_VPL_Global.Tooltip = {'一个体积内拥有的层数越多，需要越大的金字塔因子提高配准精度，通常为3'};
            app.Spinner_VPL_Global.Value = 3;
            app.Label_VPL_Global = uilabel(app.GridLayout8);
            app.Label_VPL_Global.Text = '金字塔因子';
            app.Tab_Auto_Local = uitab(app.TabGroup);
            app.Tab_Auto_Local.Tooltip = {'基于位移场估计的全自动非刚体配准方案'};
            app.Tab_Auto_Local.Title = '自动配准(局部)';
            app.Label_14 = uilabel(app.Tab_Auto_Local);
            app.Label_14.Text = '采样';
            app.DropDown_RegSampling_Local = uidropdown(app.Tab_Auto_Local);
            app.DropDown_RegSampling_Local.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_Local.ItemsData = {'MEAN', 'MEDIAN', 'NIN', 'MAX'};
            app.DropDown_RegSampling_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_LocalValueChanged, true);
            app.DropDown_RegSampling_Local.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_Local.Value = 'MEAN';
            app.Label_15 = uilabel(app.Tab_Auto_Local);
            app.Label_15.Text = '定位';
            app.DropDown_Marker_Local = uidropdown(app.Tab_Auto_Local);
            app.DropDown_Marker_Local.Items = {'红', '绿'};
            app.DropDown_Marker_Local.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Marker_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Marker_LocalValueChanged, true);
            app.DropDown_Marker_Local.Value = 'RED';
            app.CheckBox_LocalOn = uicheckbox(app.Tab_Auto_Local);
            app.CheckBox_LocalOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LocalOnValueChanged, true);
            app.CheckBox_LocalOn.Tooltip = {'启用非刚体配准'};
            app.CheckBox_LocalOn.Text = '启用';
            app.Image_GPU = uiimage(app.Tab_Auto_Local);
            app.Image_GPU.ImageClickedFcn = createCallbackFcn(app, @Image_GPUClicked, true);
            app.Image_GPU.Visible = 'off';
            app.Image_GPU.Tooltip = {'GPU状态'};
            app.Label_16 = uilabel(app.Tab_Auto_Local);
            app.Label_16.Text = '插值';
            app.DropDown_InterpAlg_Auto_Local = uidropdown(app.Tab_Auto_Local);
            app.DropDown_InterpAlg_Auto_Local.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_InterpAlg_Auto_Local.ItemsData = {'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Auto_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_Auto_LocalValueChanged, true);
            app.DropDown_InterpAlg_Auto_Local.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Auto_Local.Value = 'LINEAR';
            app.Spinner_VPL_Local = uispinner(app.Tab_Auto_Local);
            app.Spinner_VPL_Local.Limits = [2 4];
            app.Spinner_VPL_Local.ValueDisplayFormat = '%.0f';
            app.Spinner_VPL_Local.ValueChangedFcn = createCallbackFcn(app, @Spinner_VPL_LocalValueChanged, true);
            app.Spinner_VPL_Local.Tooltip = {'一个体积内拥有的层数越多，需要更大的金字塔因子，通常为3'};
            app.Spinner_VPL_Local.Value = 3;
            app.Label_VPL_Local = uilabel(app.Tab_Auto_Local);
            app.Label_VPL_Local.Text = '金字塔因子';
            app.Label_17 = uilabel(app.Tab_Auto_Local);
            app.Label_17.Text = '累积场平滑';
            app.EditField_AFS = uieditfield(app.Tab_Auto_Local, 'numeric');
            app.EditField_AFS.Limits = [0.5 3];
            app.EditField_AFS.ValueDisplayFormat = '%.2f';
            app.EditField_AFS.ValueChangedFcn = createCallbackFcn(app, @EditField_AFSValueChanged, true);
            app.EditField_AFS.Tooltip = {'数值越小，局部非刚性形变越强'};
            app.EditField_AFS.Value = 1;
            app.Label_18 = uilabel(app.Tab_Auto_Local);
            app.Label_18.Text = '信号';
            app.DropDown_Signal_Local = uidropdown(app.Tab_Auto_Local);
            app.DropDown_Signal_Local.Items = {'红', '绿'};
            app.DropDown_Signal_Local.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Signal_Local.ValueChangedFcn = createCallbackFcn(app, @DropDown_Signal_LocalValueChanged, true);
            app.DropDown_Signal_Local.Value = 'GREEN';
            app.Label_19 = uilabel(app.Tab_Auto_Local);
            app.Label_19.Text = '精度';
            app.DropDown_PhotonComAcc = uidropdown(app.Tab_Auto_Local);
            app.DropDown_PhotonComAcc.Items = {'512', '1024', '2048', '4096'};
            app.DropDown_PhotonComAcc.ItemsData = {'512', '1024', '2048', '4096'};
            app.DropDown_PhotonComAcc.ValueChangedFcn = createCallbackFcn(app, @DropDown_PhotonComAccValueChanged, true);
            app.DropDown_PhotonComAcc.Tooltip = {'淬灭补偿精度, 更高的精度代价是内存的消耗'};
            app.DropDown_PhotonComAcc.Value = '1024';
            app.CheckBox_PhotonCompOn = uicheckbox(app.Tab_Auto_Local);
            app.CheckBox_PhotonCompOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PhotonCompOnValueChanged, true);
            app.CheckBox_PhotonCompOn.Tooltip = {'如果定位通道亮度变化较明显，建议开启淬灭补偿'};
            app.CheckBox_PhotonCompOn.Text = '补偿光漂白';
            app.Label_20 = uilabel(app.Tab_Auto_Local);
            app.Label_20.Text = '迭代数上限';
            app.EditField_MaxIterN_Local = uieditfield(app.Tab_Auto_Local, 'text');
            app.EditField_MaxIterN_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LocalValueChanged, true);
            app.EditField_MaxIterN_Local.Tooltip = {'最大迭代次数，可以设置不同金字塔层的迭代上限'};
            app.EditField_MaxIterN_Local.Placeholder = '标量或数组';
            app.EditField_MaxIterN_Local.Value = '100';
            app.Label_21 = uilabel(app.Tab_Auto_Local);
            app.Label_21.Text = '配准模板';
            app.EditField_RegTemplate_Local = uieditfield(app.Tab_Auto_Local, 'text');
            app.EditField_RegTemplate_Local.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_LocalValueChanged, true);
            app.EditField_RegTemplate_Local.Value = '1';
            app.Tab_AutoReg_LongTerm = uitab(app.TabGroup);
            app.Tab_AutoReg_LongTerm.Title = '自动配准(长时程)';
            app.CheckBox_LongTermOn = uicheckbox(app.Tab_AutoReg_LongTerm);
            app.CheckBox_LongTermOn.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LongTermOnValueChanged, true);
            app.CheckBox_LongTermOn.Tooltip = {'启用刚体配准'};
            app.CheckBox_LongTermOn.Text = '启用';
            app.Label_29 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_29.Text = '关键帧';
            app.EditField_KeyFrame = uieditfield(app.Tab_AutoReg_LongTerm, 'text');
            app.EditField_KeyFrame.ValueChangedFcn = createCallbackFcn(app, @EditField_KeyFrameValueChanged, true);
            app.EditField_KeyFrame.Placeholder = '1,101,201,301,401,...';
            app.Label_30 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_30.Text = '采样';
            app.DropDown_RegSampling_LongTerm = uidropdown(app.Tab_AutoReg_LongTerm);
            app.DropDown_RegSampling_LongTerm.Items = {'平均值', '中位值', '最小值', '最大值'};
            app.DropDown_RegSampling_LongTerm.ItemsData = {'MEAN', 'MEDIAN', 'NIN', 'MAX'};
            app.DropDown_RegSampling_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegSampling_LongTermValueChanged, true);
            app.DropDown_RegSampling_LongTerm.Tooltip = {'帧运算'};
            app.DropDown_RegSampling_LongTerm.Value = 'MEAN';
            app.Label_31 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_31.Text = '配准模板';
            app.EditField_RegTemplate_LongTerm = uieditfield(app.Tab_AutoReg_LongTerm, 'text');
            app.EditField_RegTemplate_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_RegTemplate_LongTermValueChanged, true);
            app.EditField_RegTemplate_LongTerm.Value = '1';
            app.CheckBox_AutoKeyFrame = uicheckbox(app.Tab_AutoReg_LongTerm);
            app.CheckBox_AutoKeyFrame.ValueChangedFcn = createCallbackFcn(app, @CheckBox_AutoKeyFrameValueChanged, true);
            app.CheckBox_AutoKeyFrame.Text = '自动';
            app.Label_32 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_32.Text = '定位';
            app.DropDown_Marker_LongTerm = uidropdown(app.Tab_AutoReg_LongTerm);
            app.DropDown_Marker_LongTerm.Items = {'红', '绿'};
            app.DropDown_Marker_LongTerm.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Marker_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_Marker_LongTermValueChanged, true);
            app.DropDown_Marker_LongTerm.Tooltip = {''};
            app.DropDown_Marker_LongTerm.Value = 'RED';
            app.Label_33 = uilabel(app.Tab_AutoReg_LongTerm);
            app.Label_33.Text = '信号';
            app.DropDown_Signal_LongTerm = uidropdown(app.Tab_AutoReg_LongTerm);
            app.DropDown_Signal_LongTerm.Items = {'红', '绿'};
            app.DropDown_Signal_LongTerm.ItemsData = {'RED', 'GREEN'};
            app.DropDown_Signal_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_Signal_LongTermValueChanged, true);
            app.DropDown_Signal_LongTerm.Tooltip = {''};
            app.DropDown_Signal_LongTerm.Value = 'GREEN';
            app.TabGroup_LongTerm_Params = uitabgroup(app.Tab_AutoReg_LongTerm);
            app.Tab_PointCloud = uitab(app.TabGroup_LongTerm_Params);
            app.Tab_PointCloud.Title = '点云';
            app.Label_34 = uilabel(app.Tab_PointCloud);
            app.Label_34.Text = '强度下阈值';
            app.EditField_IntensityThreshold_LongTerm = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_IntensityThreshold_LongTerm.Limits = [0 100];
            app.EditField_IntensityThreshold_LongTerm.ValueDisplayFormat = '%.2f';
            app.EditField_IntensityThreshold_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_IntensityThreshold_LongTermValueChanged, true);
            app.EditField_IntensityThreshold_LongTerm.Value = 97;
            app.Label_36 = uilabel(app.Tab_PointCloud);
            app.Label_36.Text = '尺度阈值(μm)';
            app.EditField_ScaleThreshold_LongTerm = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_ScaleThreshold_LongTerm.Limits = [0.1 1000];
            app.EditField_ScaleThreshold_LongTerm.ValueDisplayFormat = '%.1f';
            app.EditField_ScaleThreshold_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_ScaleThreshold_LongTermValueChanged, true);
            app.EditField_ScaleThreshold_LongTerm.Value = 3;
            app.Label_37 = uilabel(app.Tab_PointCloud);
            app.Label_37.Text = '降采样';
            app.DropDown_DownSampling_LongTerm_PC = uidropdown(app.Tab_PointCloud);
            app.DropDown_DownSampling_LongTerm_PC.Items = {'网格平均', '随机', '非均匀'};
            app.DropDown_DownSampling_LongTerm_PC.ItemsData = {'GA', 'RAND', 'NU'};
            app.DropDown_DownSampling_LongTerm_PC.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_LongTerm_PCValueChanged, true);
            app.DropDown_DownSampling_LongTerm_PC.Value = 'GA';
            app.Label_38 = uilabel(app.Tab_PointCloud);
            app.Label_38.Text = '网格步长';
            app.EditField_DownSampling_LongTerm_Param = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_DownSampling_LongTerm_Param.Limits = [0 Inf];
            app.EditField_DownSampling_LongTerm_Param.ValueChangedFcn = createCallbackFcn(app, @EditField_DownSampling_LongTerm_ParamValueChanged, true);
            app.EditField_DownSampling_LongTerm_Param.Value = 2;
            app.Label_39 = uilabel(app.Tab_PointCloud);
            app.Label_39.Text = '异常点比例';
            app.EditField_OutlierRatio = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_OutlierRatio.Limits = [0 1];
            app.EditField_OutlierRatio.ValueDisplayFormat = '%.2f';
            app.EditField_OutlierRatio.ValueChangedFcn = createCallbackFcn(app, @EditField_OutlierRatioValueChanged, true);
            app.EditField_OutlierRatio.Value = 0.1;
            app.Label_40 = uilabel(app.Tab_PointCloud);
            app.Label_40.Text = '迭代数上限';
            app.EditField_MaxIterN_LongTerm_PC = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_MaxIterN_LongTerm_PC.Limits = [1 Inf];
            app.EditField_MaxIterN_LongTerm_PC.ValueDisplayFormat = '%.0f';
            app.EditField_MaxIterN_LongTerm_PC.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LongTerm_PCValueChanged, true);
            app.EditField_MaxIterN_LongTerm_PC.Tooltip = {'最大迭代次数'};
            app.EditField_MaxIterN_LongTerm_PC.Value = 50;
            app.Label_41 = uilabel(app.Tab_PointCloud);
            app.Label_41.Text = '全局误差限';
            app.EditField_ErrorThreshold = uieditfield(app.Tab_PointCloud, 'numeric');
            app.EditField_ErrorThreshold.Limits = [0 Inf];
            app.EditField_ErrorThreshold.ValueChangedFcn = createCallbackFcn(app, @EditField_ErrorThresholdValueChanged, true);
            app.EditField_ErrorThreshold.Tooltip = {'收敛判别步长'};
            app.EditField_ErrorThreshold.Value = 1e-05;
            app.CheckBox_LongTerm_Review = uicheckbox(app.Tab_PointCloud);
            app.CheckBox_LongTerm_Review.ValueChangedFcn = createCallbackFcn(app, @CheckBox_LongTerm_ReviewValueChanged, true);
            app.CheckBox_LongTerm_Review.Text = '预览';
            app.Tab_Voxels = uitab(app.TabGroup_LongTerm_Params);
            app.Tab_Voxels.Title = '体素';
            app.Label_42 = uilabel(app.Tab_Voxels);
            app.Label_42.Text = '最小迭代步长';
            app.EditField_MinStep_LongTerm = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_MinStep_LongTerm.Limits = [0 Inf];
            app.EditField_MinStep_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_MinStep_LongTermValueChanged, true);
            app.EditField_MinStep_LongTerm.Tooltip = {'收敛判别步长'};
            app.EditField_MinStep_LongTerm.Value = 1e-07;
            app.Label_43 = uilabel(app.Tab_Voxels);
            app.Label_43.Text = '初始迭代步长';
            app.EditField_InitStep_LongTerm = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_InitStep_LongTerm.Limits = [0 Inf];
            app.EditField_InitStep_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_InitStep_LongTermValueChanged, true);
            app.EditField_InitStep_LongTerm.Tooltip = {'起始步长'};
            app.EditField_InitStep_LongTerm.Value = 0.0625;
            app.Label_45 = uilabel(app.Tab_Voxels);
            app.Label_45.Text = '迭代因子';
            app.EditField_IterCoeff_LongTerm = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_IterCoeff_LongTerm.Limits = [0 1];
            app.EditField_IterCoeff_LongTerm.ValueDisplayFormat = '%.3f';
            app.EditField_IterCoeff_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_IterCoeff_LongTermValueChanged, true);
            app.EditField_IterCoeff_LongTerm.Tooltip = {''};
            app.EditField_IterCoeff_LongTerm.Value = 0.5;
            app.Label_47 = uilabel(app.Tab_Voxels);
            app.Label_47.Text = '降采样';
            app.DropDown_DownSampling_LongTerm_Voxel = uidropdown(app.Tab_Voxels);
            app.DropDown_DownSampling_LongTerm_Voxel.Items = {'2X2', '3X3', '关闭'};
            app.DropDown_DownSampling_LongTerm_Voxel.ItemsData = {'2X2', '3X3', 'OFF'};
            app.DropDown_DownSampling_LongTerm_Voxel.ValueChangedFcn = createCallbackFcn(app, @DropDown_DownSampling_LongTerm_VoxelValueChanged, true);
            app.DropDown_DownSampling_LongTerm_Voxel.Tooltip = {'加速配准并降低资源占用，但会降低配准精度'};
            app.DropDown_DownSampling_LongTerm_Voxel.Value = '2X2';
            app.CheckBox_PhotonCompOn_LongTerm = uicheckbox(app.Tab_Voxels);
            app.CheckBox_PhotonCompOn_LongTerm.ValueChangedFcn = createCallbackFcn(app, @CheckBox_PhotonCompOn_LongTermValueChanged, true);
            app.CheckBox_PhotonCompOn_LongTerm.Tooltip = {'如果定位通道亮度变化较明显，建议开启淬灭补偿'};
            app.CheckBox_PhotonCompOn_LongTerm.Text = '补偿光漂白';
            app.Label_48 = uilabel(app.Tab_Voxels);
            app.Label_48.Text = '累积场平滑';
            app.EditField_AFS_LongTerm = uieditfield(app.Tab_Voxels, 'numeric');
            app.EditField_AFS_LongTerm.Limits = [0.5 3];
            app.EditField_AFS_LongTerm.ValueDisplayFormat = '%.2f';
            app.EditField_AFS_LongTerm.ValueChangedFcn = createCallbackFcn(app, @EditField_AFS_LongTermValueChanged, true);
            app.EditField_AFS_LongTerm.Tooltip = {'数值越小，局部非刚性形变越强'};
            app.EditField_AFS_LongTerm.Value = 1;
            app.Label_49 = uilabel(app.Tab_Voxels);
            app.Label_49.Text = '精度';
            app.DropDown_PhotonComAcc_LongTerm = uidropdown(app.Tab_Voxels);
            app.DropDown_PhotonComAcc_LongTerm.Items = {'512', '1024', '2048', '4096'};
            app.DropDown_PhotonComAcc_LongTerm.ItemsData = {'512', '1024', '2048', '4096'};
            app.DropDown_PhotonComAcc_LongTerm.ValueChangedFcn = createCallbackFcn(app, @DropDown_PhotonComAcc_LongTermValueChanged, true);
            app.DropDown_PhotonComAcc_LongTerm.Tooltip = {'淬灭补偿精度, 更高的精度代价是内存的消耗'};
            app.DropDown_PhotonComAcc_LongTerm.Value = '1024';
            app.Label_50 = uilabel(app.Tab_Voxels);
            app.Label_50.Text = '迭代数上限';
            app.EditField_MaxIterN_LongTerm_Voxel = uieditfield(app.Tab_Voxels, 'text');
            app.EditField_MaxIterN_LongTerm_Voxel.ValueChangedFcn = createCallbackFcn(app, @EditField_MaxIterN_LongTerm_VoxelValueChanged, true);
            app.EditField_MaxIterN_LongTerm_Voxel.Tooltip = {'最大迭代次数，可以设置不同金字塔层的迭代上限'};
            app.EditField_MaxIterN_LongTerm_Voxel.Placeholder = '标量或数组';
            app.EditField_MaxIterN_LongTerm_Voxel.Value = '100,50,25';
            app.Tab_ManualReg = uitab(app.TabGroup);
            app.Tab_ManualReg.Tooltip = {'基于控制点方法的手动配准方案'};
            app.Tab_ManualReg.Title = '手动配准(全局)';
            app.Label_22 = uilabel(app.Tab_ManualReg);
            app.Label_22.Text = '几何变换';
            app.DropDown_RegTform_Manual = uidropdown(app.Tab_ManualReg);
            app.DropDown_RegTform_Manual.Items = {'非反射相似', '仿射', '投影', '分段线性', '多项式', '局部加权平均'};
            app.DropDown_RegTform_Manual.ItemsData = {'SIMILARITY', 'AFFINE', 'PROJECTION', 'PWL', 'POLYNOMIAL', 'LWM'};
            app.DropDown_RegTform_Manual.ValueChangedFcn = createCallbackFcn(app, @DropDown_RegTform_ManualValueChanged, true);
            app.DropDown_RegTform_Manual.Tooltip = {'几何变换类型'};
            app.DropDown_RegTform_Manual.Value = 'SIMILARITY';
            app.Label_23 = uilabel(app.Tab_ManualReg);
            app.Label_23.Text = '最小控制量';
            app.EditField_CPNumber = uieditfield(app.Tab_ManualReg, 'numeric');
            app.EditField_CPNumber.Limits = [2 Inf];
            app.EditField_CPNumber.ValueDisplayFormat = '%.0f';
            app.EditField_CPNumber.Editable = 'off';
            app.EditField_CPNumber.Tooltip = {'控制点对数量的下限'};
            app.EditField_CPNumber.Value = 2;
            app.Label_24 = uilabel(app.Tab_ManualReg);
            app.Label_24.Text = '多项式次数';
            app.Spinner_PolyOrder = uispinner(app.Tab_ManualReg);
            app.Spinner_PolyOrder.Limits = [2 4];
            app.Spinner_PolyOrder.ValueDisplayFormat = '%.0f';
            app.Spinner_PolyOrder.ValueChangedFcn = createCallbackFcn(app, @Spinner_PolyOrderValueChanged, true);
            app.Spinner_PolyOrder.Tooltip = {'多项式变换参数'};
            app.Spinner_PolyOrder.Value = 3;
            app.Label_25 = uilabel(app.Tab_ManualReg);
            app.Label_25.Text = '加权点数';
            app.Spinner_WPN = uispinner(app.Tab_ManualReg);
            app.Spinner_WPN.Limits = [6 18];
            app.Spinner_WPN.ValueDisplayFormat = '%.0f';
            app.Spinner_WPN.ValueChangedFcn = createCallbackFcn(app, @Spinner_WPNValueChanged, true);
            app.Spinner_WPN.Tooltip = {'局部加权平均参数'};
            app.Spinner_WPN.Value = 12;
            app.Label_26 = uilabel(app.Tab_ManualReg);
            app.Label_26.Text = '插值';
            app.DropDown_InterpAlg_Manual = uidropdown(app.Tab_ManualReg);
            app.DropDown_InterpAlg_Manual.Items = {'线性(快速)', '立方(高质量)'};
            app.DropDown_InterpAlg_Manual.ItemsData = {'LINEAR', 'CUBIC'};
            app.DropDown_InterpAlg_Manual.ValueChangedFcn = createCallbackFcn(app, @DropDown_InterpAlg_ManualValueChanged, true);
            app.DropDown_InterpAlg_Manual.Tooltip = {'图像插值算法'};
            app.DropDown_InterpAlg_Manual.Value = 'LINEAR';
            app.ButtonGroup_Smooth = uibuttongroup(app.Tab_ManualReg);
            app.ButtonGroup_Smooth.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_SmoothSelectionChanged, true);
            app.ButtonGroup_Smooth.Tooltip = {'几何变换边界平滑，默认开启'};
            app.ButtonGroup_Smooth.Title = '边界平滑';
            app.Button_Smooth_Manual_Y = uiradiobutton(app.ButtonGroup_Smooth);
            app.Button_Smooth_Manual_Y.Text = '是';
            app.Button_Smooth_Manual_Y.Value = true;
            app.Button_Smooth_Manual_N = uiradiobutton(app.ButtonGroup_Smooth);
            app.Button_Smooth_Manual_N.Text = '否';
            app.Label_27 = uilabel(app.Tab_ManualReg);
            app.Label_27.Text = '模板同步';
            app.DropDown_TemplateSync = uidropdown(app.Tab_ManualReg);
            app.DropDown_TemplateSync.Items = {'自动配准(全局)', '自动配准(局部)'};
            app.DropDown_TemplateSync.ItemsData = {'GLOBAL', 'LOCAL'};
            app.DropDown_TemplateSync.ValueChangedFcn = createCallbackFcn(app, @DropDown_TemplateSyncValueChanged, true);
            app.DropDown_TemplateSync.Value = 'GLOBAL';
            % Show the figure after all components are created
            app.RSTCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegSetting_TC(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RSTCUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RSTCUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RSTCUIFigure)
        end
    end
end