classdef ResourceManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResourceManagerUIFigure        matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        Tab_Preference                 matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout10                   matlab.ui.container.GridLayout
        Button_Cancel                  matlab.ui.control.Button
        Button_OK                      matlab.ui.control.Button
        Button_Apply                   matlab.ui.control.Button
        GridLayout6                    matlab.ui.container.GridLayout
        Panel_Options                  matlab.ui.container.Panel
        GridLayout_Options             matlab.ui.container.GridLayout
        DropDown_CachePolicy           matlab.ui.control.DropDown
        Label_20                       matlab.ui.control.Label
        Switch_AutoTemplate            matlab.ui.control.Switch
        Label_19                       matlab.ui.control.Label
        Switch_SimpleStatistics        matlab.ui.control.Switch
        Label_18                       matlab.ui.control.Label
        DropDown_Language              matlab.ui.control.DropDown
        Label_17                       matlab.ui.control.Label
        PythonLabel                    matlab.ui.control.Label
        GridLayout11                   matlab.ui.container.GridLayout
        Button_SelectPython            matlab.ui.control.Button
        EditField_PyPath               matlab.ui.control.EditField
        Switch_DataProtected           matlab.ui.control.Switch
        Label_16                       matlab.ui.control.Label
        Switch_ExperimentalFeatures    matlab.ui.control.Switch
        Label_15                       matlab.ui.control.Label
        DropDown_UpdateChannel         matlab.ui.control.DropDown
        Label_13                       matlab.ui.control.Label
        DropDown_AutoUpdate            matlab.ui.control.DropDown
        Label_12                       matlab.ui.control.Label
        DropDown_MessageLevel          matlab.ui.control.DropDown
        Label_10                       matlab.ui.control.Label
        DropDown_Style                 matlab.ui.control.DropDown
        Label_9                        matlab.ui.control.Label
        Spinner_GPU_Protected          matlab.ui.control.Spinner
        GPULabel                       matlab.ui.control.Label
        Spinner_CPU_Protected          matlab.ui.control.Spinner
        CPULabel                       matlab.ui.control.Label
        DropDown_BranchDepth           matlab.ui.control.DropDown
        Label_8                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        GridLayout9                    matlab.ui.container.GridLayout
        Button_RequireCapacity         matlab.ui.control.Button
        EditField_BufferCapacity       matlab.ui.control.NumericEditField
        DropDown_BufferCleanTrigger    matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        GridLayout8                    matlab.ui.container.GridLayout
        DropDown_ProgressBarColor      matlab.ui.control.DropDown
        EditField_ProgressBarColor     matlab.ui.control.EditField
        Label_4                        matlab.ui.control.Label
        Tree_OptionGroups              matlab.ui.container.Tree
        Node_Appearance                matlab.ui.container.TreeNode
        Node_Style                     matlab.ui.container.TreeNode
        Node_ImageToolbar              matlab.ui.container.TreeNode
        Node_DataManagement            matlab.ui.container.TreeNode
        Node_TransmissionOptimization  matlab.ui.container.TreeNode
        Node_TemporaryFile             matlab.ui.container.TreeNode
        Node_History                   matlab.ui.container.TreeNode
        Node_ParallelComputing         matlab.ui.container.TreeNode
        Node_ConfigurateCPU            matlab.ui.container.TreeNode
        Node_ConfigurateGPU            matlab.ui.container.TreeNode
        Node_Others                    matlab.ui.container.TreeNode
        Node_MessageLevel              matlab.ui.container.TreeNode
        Node_DataSecurity              matlab.ui.container.TreeNode
        Node_ExternalLibraryPath       matlab.ui.container.TreeNode
        Node_SystemUpdate              matlab.ui.container.TreeNode
        Tab_TaskNetwork                matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_TaskNetworkTopology      matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        UIAxes_TaskNetwork             matlab.ui.control.UIAxes
        Panel_TaskTable                matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        UITable_TaskView               matlab.ui.control.Table
    end

    properties (Constant)
        VER = "1.0.0"
    end

    properties (Constant, Hidden)
        GRID_ROW_HEIGHT = 25
        FEATURES_NUMBER = 17
        NRSCPHN = 4              % non-resource place holders number
    end

    properties (Access = private)
        caller      (1,1)                       % the caller
        aopts       (1,1)   struct              % appearance options
        nwdaemon    (1,1)                       % the task network (appearance) refresh daemon timer
        rsctb       (:,:)   table               % the resource table
        nwview      (1,1)   logical = false     % the network view flag
        langmap     (1,1)   struct              % mapping for language
    end

    methods(Access=?Register)
        
        function RefreshPanelStyle(app)
            style = app.caller.GetAppStyle();

            if style == "FOLLOW"
                hr = datetime("now").Hour;
                if hr >= 6 && hr <= 18
                    style = "LIGHT";
                else
                    style = "DARK";
                end
            end

            switch style
                case "LIGHT"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout9.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.Tree_OptionGroups.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                    app.Panel_Options.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout_Options.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_LIGHT;
                    app.UITable_TaskView.BackgroundColor = constdef.GRAYSCALE_AXES_LIGHT;
                    app.UIAxes_TaskNetwork.Color = constdef.GRAYSCALE_AXES_LIGHT;
                case "DARK"
                    app.GridLayout.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout5.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout6.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout8.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout9.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout10.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout11.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.Tree_OptionGroups.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                    app.Panel_Options.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout_Options.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout2.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout3.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.GridLayout4.BackgroundColor = constdef.GRAYSCALE_DARK;
                    app.UITable_TaskView.BackgroundColor = constdef.GRAYSCALE_AXES_DARK;
                    app.UIAxes_TaskNetwork.Color = constdef.GRAYSCALE_AXES_DARK;
                otherwise
            end
        end

        function RefreshPanelLanguage(app)
            % get language map
            [~, app.langmap] = app.caller.GetLangsInfo();
            
            % apply languages
            app.Tab_Preference.Title = app.langmap.PREFERENCE;
            app.Tab_TaskNetwork.Title = app.langmap.TASK_NETWORK;
            app.Panel_Options.Title = app.langmap.OPTION;
            app.Panel_TaskTable.Title = app.langmap.TASK_TABLE;
            app.Panel_TaskNetworkTopology.Title = app.langmap.NETWORK_TOPOLOGY;

            app.Node_Appearance.Text = app.langmap.APPEARANCE;
            app.Node_Style.Text = app.langmap.STYLE;
            app.Node_ImageToolbar.Text = app.langmap.IMAGE_TOOLBAR;
            app.Node_DataManagement.Text = app.langmap.DATA_MANAGEMENT;
            app.Node_TransmissionOptimization.Text = app.langmap.TRANSMISSION_OPTIMIZATION;
            app.Node_TemporaryFile.Text = app.langmap.TEMPORARY_FILE;
            app.Node_History.Text = app.langmap.HISTORY;
            app.Node_ParallelComputing.Text = app.langmap.PARALLEL_COMPUTING;
            app.Node_ConfigurateCPU.Text = app.langmap.CONFIGURATE_CPU;
            app.Node_ConfigurateGPU.Text = app.langmap.CONFIGURATE_GPU;
            app.Node_Others.Text = app.langmap.OTHER_SETTINGS;
            app.Node_MessageLevel.Text = app.langmap.MESSAGE_LEVEL;
            app.Node_DataSecurity.Text = app.langmap.DATA_SECURITY;
            app.Node_ExternalLibraryPath.Text = app.langmap.EXTERNAL_PATH;
            app.Node_SystemUpdate.Text = app.langmap.SYS_UPDATE;

            app.Label_17.Text = app.langmap.LANGUAGE;
            app.Label_9.Text = app.langmap.STYLE;
            app.Label_4.Text = app.langmap.PROGRESSBAR_COLOR;
            app.Label_18.Text = app.langmap.SIMPLE_STAT;
            app.Label_19.Text = app.langmap.AUTO_TEMPLATE;
            app.Label_5.Text = app.langmap.CLEAN_BUFFER;
            app.Label_6.Text = app.langmap.CAPACITY_MAX;
            app.Label_8.Text = app.langmap.OPERATION_TREE_DEPTH;
            app.CPULabel.Text = app.langmap.PROTECTED_NUMBER_CPU;
            app.GPULabel.Text = app.langmap.PROTECTED_NUMBER_GPU;
            app.Label_10.Text = app.langmap.MESSAGE_LEVEL;
            app.Label_16.Text = app.langmap.DATA_PROTECTED;
            app.PythonLabel.Text = app.langmap.PYTHON_INTERPRETER;
            app.Label_12.Text = app.langmap.AUTO_UPDATE;
            app.Label_13.Text = app.langmap.CHANNEL_UPDATE;
            app.Label_15.Text = app.langmap.EXPERIMENTAL_FEATURE;
            app.Label_20.Text = app.langmap.CACHE_POLICY;

            app.DropDown_Language.Items = ["中文", "English", app.langmap.FOLLOW_SYSTEM];
            app.DropDown_Style.Items = [app.langmap.STYLE_LIGHT, ...
                app.langmap.STYLE_DARK, app.langmap.FOLLOW_SYSTEM];
            app.DropDown_CachePolicy.Items = [app.langmap.PERFORMANCE, ...
                app.langmap.RESOURCES, app.langmap.BALANCE];
            app.DropDown_BufferCleanTrigger.Items = [app.langmap.BCS_EXIT, ...
                app.langmap.BCS_RT, app.langmap.BCS_OFF];
            app.DropDown_BranchDepth.Items = [app.langmap.OTO_OP_ONE, ...
                app.langmap.OTO_OP_THREE, app.langmap.OTO_OP_INF, app.langmap.OTO_OP_ZERO];
            app.DropDown_MessageLevel.Items = [app.langmap.WARNING, ...
                app.langmap.INFORMATION];
            app.DropDown_AutoUpdate.Items = [app.langmap.STARTUP, app.langmap.REAL_TIME, ...
                app.langmap.EVERYDAY, app.langmap.OFF];
            app.DropDown_UpdateChannel.Items = [app.langmap.RELEASE_CHANNEL, ...
                app.langmap.PREVIEW_CHANNEL, app.langmap.ALL_CHANNEL];

            app.Switch_SimpleStatistics.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_AutoTemplate.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_DataProtected.Items = [app.langmap.DISABLE, app.langmap.ENABLE];
            app.Switch_ExperimentalFeatures.Items = [app.langmap.DISABLE, app.langmap.ENABLE];

            app.Button_Apply.Text = app.langmap.APPLY;
            app.Button_OK.Text = app.langmap.OK;
            app.Button_Cancel.Text = app.langmap.CANCEL;
        end

        function LockPreferencePanel(app)
            app.Tree_OptionGroups.Enable = "off";
            app.Panel_Options.Enable = "off";
            app.Button_Apply.Enable = "off";
            app.Button_OK.Enable = "off";
            app.Button_Cancel.Enable = "off";
        end

        function UnlockPreferencePanel(app)
            app.Tree_OptionGroups.Enable = "on";
            app.Panel_Options.Enable = "on";
            app.Button_Apply.Enable = "on";
            app.Button_OK.Enable = "on";
            app.Button_Cancel.Enable = "on";
        end

        function Close(app)
            % stop daemon
            stop(app.nwdaemon);
            delete(app.nwdaemon);

            % ~
            delete(app)
        end
    end
    
    methods (Access = private)
        function InitData(app)
            app.aopts = app.caller.GetAppProfile();

            % timer for task net work refresh
            app.nwdaemon = timer("Period",1, "BusyMode","queue", ...
                "ExecutionMode","fixedRate", "TasksToExecute",inf,...
                "Name","Timer_TaskNetDaemon", "TimerFcn",@app.UpdateTaskNetView);

            CPU_MAX_N = GetCPUWorkersMaxN([],[]);
            GPU_MAX_N = GetGPUWorkersMaxN();
            app.Spinner_CPU_Protected.Limits = [0, CPU_MAX_N];
            app.Spinner_GPU_Protected.Limits = [0, GPU_MAX_N];

            BUFFER_SIZE_MAX = mpimg.GetBufferSizeMax();
            app.EditField_BufferCapacity.Limits = [1, BUFFER_SIZE_MAX];

            r = app.caller.GetEngineInfo();
            nwp = r.NWP;
            app.Spinner_CPU_Protected.Value = nwp(1);
            app.Spinner_GPU_Protected.Value = nwp(2);
        end

        function SaveProfile(app)
            prf_file = app.caller.GetAppProfileFullPath();
            [fpath, ~, ~] = fileparts(prf_file);
            if ~isfolder(fpath)
                try mkdir(fpath); catch ME, throw(ME); end
            end

            try
                writestruct(app.aopts, prf_file, "FileType","xml");
            catch ME
                throw(MException("ResourceManager:permissionDenied", ...
                    app.langmap.PERMISSION_DENIED));
            end
        end

        function InitAppearance(app)
            %% Init Appearance Group
            app.DropDown_Language.Value = app.aopts.Language;
            app.Switch_SimpleStatistics.Value = app.aopts.SimpleStatistics;
            app.Switch_AutoTemplate.Value = app.aopts.AutoTemplate;
            app.DropDown_Style.Value = app.aopts.Style;
            app.EditField_ProgressBarColor.Value = string(app.aopts.ProgressBarColor).join(",");
            app.DropDown_ProgressBarColor.BackgroundColor = app.aopts.ProgressBarColor;

            %% Init Data Management Group
            app.DropDown_CachePolicy.Value = app.aopts.CachePolicy;
            app.DropDown_BufferCleanTrigger.Value = app.aopts.BufferCleanTrigger;
            app.EditField_BufferCapacity.Value = app.aopts.Capacity;
            app.DropDown_BranchDepth.Value = app.aopts.BranchDepth;

            %% Init Parallel Computing Group
            app.Spinner_CPU_Protected.Value = app.aopts.NumProtectedCPU;
            app.Spinner_GPU_Protected.Value = app.aopts.NumProtectedGPU;

            %% Init Others Group
            app.DropDown_MessageLevel.Value = app.aopts.MessageLevel;
            app.Switch_DataProtected.Value = app.aopts.DataProtected;
            app.EditField_PyPath.Value = app.aopts.PythonPath;
            app.DropDown_AutoUpdate.Value = app.aopts.AutoUpdate;
            app.DropDown_UpdateChannel.Value = app.aopts.UpdateChannel;
            app.Switch_ExperimentalFeatures.Value = app.aopts.ExperimentalFeature;

            % refresh Options
            app.Tree_OptionGroups.SelectedNodes = app.Node_Appearance;
            app.Node_Appearance.expand();
            DisplayOptionsSwitchTo(app, "APPEARANCE");
        end
        
        function HiddenAllOptions(app)
            app.GridLayout_Options.RowHeight = zeros(1, app.FEATURES_NUMBER);
        end

        function DisplayOptionsSwitchTo(app, Tag)
            switch Tag
                case "APPEARANCE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [1, 1, 1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "IMAGE_TOOLBAR"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "STYLE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "DATA_MANAGEMENT"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 1, 1, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0];
                case "TRANSMISSION_OPTIMIZATION"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "TEMPORARY_FILE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 1, 1, 0, 0, 0, 0, 0, 0, 0, 0, 0];
                case "HISTORY"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0, 0];
                case "PARALLEL_COMPUTING"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 0, 0, 0, 0, 0, 0];
                case "CPU_PROFILE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0, 0];
                case "GPU_PROFILE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0, 0];
                case "OTHERS"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 1, 1, 1, 1];
                case "INFO_LEVEL"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0, 0];
                case "DATA_SECURITY"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0, 0];
                case "EXTERNAL_PATH"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 0, 0, 0];
                case "SYSTEM_UPDATE"
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        [0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 1, 1, 1];
                otherwise
                    app.GridLayout_Options.RowHeight = app.GRID_ROW_HEIGHT* ...
                        zeros(1, app.FEATURES_NUMBER);
            end
        end

        % be invoked by nwdaemon: inf loops
        function UpdateTaskNetView(app, ~, ~)
            % task table view
            UpdateTaskTableView(app);

            % task nework view
            UpdateTaskTopologyView(app);

            drawnow nocallbacks
        end

        function UpdateTaskTableView(app)
            r = app.caller.GetEngineInfo();
            app.rsctb = r.T;    % update resource table
            app.UITable_TaskView.Data = r.T;
            pawait = find(r.T.status == "Await");
            pawait = [pawait, repmat(2, numel(pawait), 1)];
            pready = find(r.T.status == "Ready");
            pready = [pready, repmat(2, numel(pready), 1)];
            prun = find(r.T.status == "Run");
            prun = [prun, repmat(2, numel(prun), 1)];

            sawait = uistyle("Icon","error","IconAlignment","rightmargin");
            sready = uistyle("Icon","warning","IconAlignment","rightmargin");
            srun = uistyle("Icon","success","IconAlignment","rightmargin");

            % refresh the table style
            removeStyle(app.UITable_TaskView);
            if ~isempty(pawait)
                addStyle(app.UITable_TaskView, sawait, "cell", pawait);
            end
            if ~isempty(pready)
                addStyle(app.UITable_TaskView, sready, "cell", pready);
            end
            if ~isempty(prun)
                addStyle(app.UITable_TaskView, srun, "cell", prun);
            end
        end

        function UpdateTaskTopologyView(app)
            % generate adjencient matrix:
            % [row, column] -> [resource, user]
            % weight for resource count
            netm = table2array(app.rsctb(:, app.NRSCPHN+1:end))';

            if ~isempty(netm)
                % create resource bipartite graph
                bnetm = zeros(sum(size(netm)), sum(size(netm)));
                bnetm(1:size(netm,1), end-size(netm,2)+1:end) = netm;

                % generate node name
                nodes = string(app.rsctb.Properties.VariableNames(app.NRSCPHN+1:end));
                nodec = hsv(numel(nodes));
                nodes = [nodes'; app.rsctb.user];
                nodec = [nodec; zeros(numel(app.rsctb.user), 3)];

                rG = digraph(bnetm, nodes);

                % mark line by user status
                line_stl = strings(size(rG.Edges, 1), 1);
                for k = 1:size(rG.Edges, 1)
                    % validate user status
                    status = app.rsctb.status(app.rsctb.user==string(rG.Edges.EndNodes(k, 2)));
                    switch status
                        case "Run"
                            line_stl(k) = "-";
                        case "Await"
                            line_stl(k) = "--";
                        otherwise
                            line_stl(k) = "none";
                    end
                end

                plot(app.UIAxes_TaskNetwork, rG, "Layout","layered", ...
                    "EdgeLabel",rG.Edges.Weight, "NodeColor",nodec, ...
                    "LineWidth", 5*rG.Edges.Weight/app.Spinner_CPU_Protected.Limits(2), ...
                    "MarkerSize",10, "NodeFontSize",14, "EdgeFontSize",12, ...
                    "ArrowSize",18,"ArrowPosition",1, "EdgeFontWeight","bold", ...
                    "LineStyle", line_stl, "Interpreter","none");
            else
                cla(app.UIAxes_TaskNetwork);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            HiddenAllOptions(app);

            app.caller = mainapp;

            InitData(app);

            InitAppearance(app);

            RefreshPanelStyle(app);

            RefreshPanelLanguage(app);
        end

        % Selection changed function: Tree_OptionGroups
        function Tree_OptionGroupsSelectionChanged(app, event)
            selectedNodes = app.Tree_OptionGroups.SelectedNodes;
            
            app.DisplayOptionsSwitchTo(selectedNodes.Tag);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            switch selectedTab
                case app.Tab_TaskNetwork
                    stop(app.nwdaemon);     % security trigger
                    start(app.nwdaemon);
                case app.Tab_Preference
                    % stop refresh to save kernel visiting
                    stop(app.nwdaemon);
                otherwise
            end
        end

        % Close request function: ResourceManagerUIFigure
        function ResourceManagerUIFigureCloseRequest(app, event)

            Close(app);            
        end

        % Value changed function: DropDown_Style
        function DropDown_StyleValueChanged(app, event)
            value = app.DropDown_Style.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.Style = string(value);
        end

        % Value changed function: EditField_ProgressBarColor
        function EditField_ProgressBarColorValueChanged(app, event)
            value = app.EditField_ProgressBarColor.Value;
            value = str2num(value); %#ok<ST2NM>

            if isempty(value) || any(size(value)~=[1,3]) || any(value<0 | value>1)
                app.EditField_ProgressBarColor.Value = string(app.aopts).join(",");
            else
                app.Button_Apply.Enable = "on";
                app.DropDown_ProgressBarColor.BackgroundColor = value;
                app.aopts.ProgressBarColor = value;
            end
        end

        % Drop down opening function: DropDown_ProgressBarColor
        function DropDown_ProgressBarColorOpening(app, event)
            rgb = uisetcolor(app.aopts.ProgressBarColor);
            rgb = round(rgb, 2);
            app.Button_Apply.Enable = "on";

            app.EditField_ProgressBarColor.Value = ...
                sprintf("%1.2f,%1.2f,%1.2f", rgb(1), rgb(2), rgb(3));
            app.DropDown_ProgressBarColor.BackgroundColor = rgb;

            app.aopts.ProgressBarColor = rgb;
        end

        % Value changed function: DropDown_BufferCleanTrigger
        function DropDown_BufferCleanTriggerValueChanged(app, event)
            value = app.DropDown_BufferCleanTrigger.Value;
            app.Button_Apply.Enable = "on";

            app.aopts.CleanBufferTrigger = string(value);
        end

        % Value changed function: EditField_BufferCapacity
        function EditField_BufferCapacityValueChanged(app, event)
            value = app.EditField_BufferCapacity.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.Capacity = value;
        end

        % Value changed function: DropDown_BranchDepth
        function DropDown_BranchDepthValueChanged(app, event)
            value = app.DropDown_BranchDepth.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.OperationTreeDepth = string(value);
        end

        % Value changed function: Spinner_CPU_Protected
        function Spinner_CPU_ProtectedValueChanged(app, event)
            value = app.Spinner_CPU_Protected.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.NumProtectedCPU = value;
        end

        % Value changed function: Spinner_GPU_Protected
        function Spinner_GPU_ProtectedValueChanged(app, event)
            value = app.Spinner_GPU_Protected.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.NumProtectedGPU = value;
        end

        % Value changed function: DropDown_MessageLevel
        function DropDown_MessageLevelValueChanged(app, event)
            value = app.DropDown_MessageLevel.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.MessageLevel = string(value);
        end

        % Value changed function: DropDown_AutoUpdate
        function DropDown_AutoUpdateValueChanged(app, event)
            value = app.DropDown_AutoUpdate.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.AutoUpdate = string(value);
        end

        % Value changed function: DropDown_UpdateChannel
        function DropDown_UpdateChannelValueChanged(app, event)
            value = app.DropDown_UpdateChannel.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.UpdateChannel = string(value);
        end

        % Button pushed function: Button_RequireCapacity
        function Button_RequireCapacityPushed(app, event)
            uialert(app.ResourceManagerUIFigure, app.langmap.REQUIRE_CAPACITY, ...
                app.langmap.INFORMATION, "Icon","info");
            
        end

        % Button pushed function: Button_Apply
        function Button_ApplyPushed(app, event)
            % set and apply, save configuration, do not close
            app.ResourceManagerUIFigure.Pointer = "watch";
            drawnow;

            app.caller.SetAppProfile(app.aopts);
            SaveProfile(app);
            app.Button_Apply.Enable = "off";

            app.ResourceManagerUIFigure.Pointer = "arrow";
            drawnow;
        end

        % Button pushed function: Button_OK
        function Button_OKPushed(app, event)
            % set and apply, save configuration, close
            if app.Button_Apply.Enable == "on"  % changed some items but didn't save
                app.ResourceManagerUIFigure.Pointer = "watch";
                drawnow;

                app.caller.SetAppProfile(app.aopts);
                SaveProfile(app);

                app.ResourceManagerUIFigure.Pointer = "arrow";
                drawnow;
            end

            Close(app);
        end

        % Button pushed function: Button_Cancel
        function Button_CancelPushed(app, event)
            % close only
            Close(app);
        end

        % Value changed function: Switch_SimpleStatistics
        function Switch_SimpleStatisticsValueChanged(app, event)
            value = app.Switch_SimpleStatistics.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.SimpleStatistics = string(value);
        end

        % Value changed function: Switch_AutoTemplate
        function Switch_AutoTemplateValueChanged(app, event)
            value = app.Switch_AutoTemplate.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.AutoTemplate = string(value);
        end

        % Value changed function: Switch_DataProtected
        function Switch_DataProtectedValueChanged(app, event)
            value = app.Switch_DataProtected.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.DataProtected = string(value);
        end

        % Value changed function: Switch_ExperimentalFeatures
        function Switch_ExperimentalFeaturesValueChanged(app, event)
            value = app.Switch_ExperimentalFeatures.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.ExperimentalFeature = string(value);
        end

        % Value changed function: DropDown_Language
        function DropDown_LanguageValueChanged(app, event)
            value = app.DropDown_Language.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.Language = string(value);
        end

        % Button pushed function: Button_SelectPython
        function Button_SelectPythonPushed(app, event)
            if app.aopts.PythonPath == ""
                if ispc()
                    defname = "python.exe";
                elseif isunix()
                    defname = "python";
                end
                [file, path] = uigetfile(defname, ...
                    app.langmap.PYTHON_INTERPRETER_SELECTION);
            else
                [file, path] = uigetfile(app.aopts.PythonPath, ...
                    app.langmap.PYTHON_INTERPRETER_SELECTION);
            end

            if ~isnumeric(file)
                file = fullfile(path, file);
                try
                    % reload python process
                    terminate(pyenv);
                    pyenv(Version=file, ExecutionMode="OutOfProcess");

                    % update file path
                    app.EditField_PyPath.Value = file;
                    app.aopts.PythonPath = string(file);
                catch ME
                    throwAsCaller(ME);
                end
            end
        end

        % Value changed function: DropDown_CachePolicy
        function DropDown_CachePolicyValueChanged(app, event)
            value = app.DropDown_CachePolicy.Value;
            app.Button_Apply.Enable = "on";
            
            app.aopts.CachePolicy = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResourceManagerUIFigure = uifigure('Visible', 'off');
            app.ResourceManagerUIFigure.Name = 'Resource Manager';
            app.ResourceManagerUIFigure.Icon = 'resource_manager.png';
            app.ResourceManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @ResourceManagerUIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.ResourceManagerUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_Preference = uitab(app.TabGroup);
            app.Tab_Preference.Title = '首选项';
            app.GridLayout5 = uigridlayout(app.Tab_Preference);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.Tree_OptionGroups = uitree(app.GridLayout6);
            app.Tree_OptionGroups.SelectionChangedFcn = createCallbackFcn(app, @Tree_OptionGroupsSelectionChanged, true);
            app.Node_Appearance = uitreenode(app.Tree_OptionGroups);
            app.Node_Appearance.Tag = 'APPEARANCE';
            app.Node_Appearance.Text = '外观';
            app.Node_Style = uitreenode(app.Node_Appearance);
            app.Node_Style.Tag = 'STYLE';
            app.Node_Style.Text = '风格';
            app.Node_ImageToolbar = uitreenode(app.Node_Appearance);
            app.Node_ImageToolbar.Tag = 'IMAGE_TOOLBAR';
            app.Node_ImageToolbar.Text = '图像工具栏';
            app.Node_DataManagement = uitreenode(app.Tree_OptionGroups);
            app.Node_DataManagement.Tag = 'DATA_MANAGEMENT';
            app.Node_DataManagement.Text = '数据管理';
            app.Node_TransmissionOptimization = uitreenode(app.Node_DataManagement);
            app.Node_TransmissionOptimization.Tag = 'TRANSMISSION_OPTIMIZATION';
            app.Node_TransmissionOptimization.Text = '传递优化';
            app.Node_TemporaryFile = uitreenode(app.Node_DataManagement);
            app.Node_TemporaryFile.Tag = 'TEMPORARY_FILE';
            app.Node_TemporaryFile.Text = '临时文件';
            app.Node_History = uitreenode(app.Node_DataManagement);
            app.Node_History.Tag = 'HISTORY';
            app.Node_History.Text = '历史记录';
            app.Node_ParallelComputing = uitreenode(app.Tree_OptionGroups);
            app.Node_ParallelComputing.Tag = 'PARALLEL_COMPUTING';
            app.Node_ParallelComputing.Text = '并行计算';
            app.Node_ConfigurateCPU = uitreenode(app.Node_ParallelComputing);
            app.Node_ConfigurateCPU.Tag = 'CPU_PROFILE';
            app.Node_ConfigurateCPU.Text = '配置CPU';
            app.Node_ConfigurateGPU = uitreenode(app.Node_ParallelComputing);
            app.Node_ConfigurateGPU.Tag = 'GPU_PROFILE';
            app.Node_ConfigurateGPU.Text = '配置GPU';
            app.Node_Others = uitreenode(app.Tree_OptionGroups);
            app.Node_Others.Tag = 'OTHERS';
            app.Node_Others.Text = '其他设置';
            app.Node_MessageLevel = uitreenode(app.Node_Others);
            app.Node_MessageLevel.Tag = 'INFO_LEVEL';
            app.Node_MessageLevel.Text = '消息级别';
            app.Node_DataSecurity = uitreenode(app.Node_Others);
            app.Node_DataSecurity.Tag = 'DATA_SECURITY';
            app.Node_DataSecurity.Text = '数据安全';
            app.Node_ExternalLibraryPath = uitreenode(app.Node_Others);
            app.Node_ExternalLibraryPath.Tag = 'EXTERNAL_PATH';
            app.Node_ExternalLibraryPath.Text = '外部路径';
            app.Node_SystemUpdate = uitreenode(app.Node_Others);
            app.Node_SystemUpdate.Tag = 'SYSTEM_UPDATE';
            app.Node_SystemUpdate.Text = '系统更新';
            app.Panel_Options = uipanel(app.GridLayout6);
            app.Panel_Options.Title = '选项';
            app.GridLayout_Options = uigridlayout(app.Panel_Options);
            app.Label_4 = uilabel(app.GridLayout_Options);
            app.Label_4.Text = '进度条颜色';
            app.GridLayout8 = uigridlayout(app.GridLayout_Options);
            app.EditField_ProgressBarColor = uieditfield(app.GridLayout8, 'text');
            app.EditField_ProgressBarColor.CharacterLimits = [5 16];
            app.EditField_ProgressBarColor.ValueChangedFcn = createCallbackFcn(app, @EditField_ProgressBarColorValueChanged, true);
            app.EditField_ProgressBarColor.Value = '0.30,0.75,0.93';
            app.DropDown_ProgressBarColor = uidropdown(app.GridLayout8);
            app.DropDown_ProgressBarColor.Items = {};
            app.DropDown_ProgressBarColor.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_ProgressBarColorOpening, true);
            app.DropDown_ProgressBarColor.Value = {};
            app.Label_5 = uilabel(app.GridLayout_Options);
            app.Label_5.Text = '缓存清理';
            app.DropDown_BufferCleanTrigger = uidropdown(app.GridLayout_Options);
            app.DropDown_BufferCleanTrigger.Items = {'退出时', '实时', '从不(不推荐)'};
            app.DropDown_BufferCleanTrigger.ItemsData = {'EXIT', 'RT', 'OFF'};
            app.DropDown_BufferCleanTrigger.ValueChangedFcn = createCallbackFcn(app, @DropDown_BufferCleanTriggerValueChanged, true);
            app.DropDown_BufferCleanTrigger.Value = 'EXIT';
            app.GridLayout9 = uigridlayout(app.GridLayout_Options);
            app.EditField_BufferCapacity = uieditfield(app.GridLayout9, 'numeric');
            app.EditField_BufferCapacity.Limits = [1 128];
            app.EditField_BufferCapacity.RoundFractionalValues = 'on';
            app.EditField_BufferCapacity.ValueDisplayFormat = '%.0f';
            app.EditField_BufferCapacity.ValueChangedFcn = createCallbackFcn(app, @EditField_BufferCapacityValueChanged, true);
            app.EditField_BufferCapacity.Value = 128;
            app.Button_RequireCapacity = uibutton(app.GridLayout9, 'push');
            app.Button_RequireCapacity.ButtonPushedFcn = createCallbackFcn(app, @Button_RequireCapacityPushed, true);
            app.Button_RequireCapacity.Text = 'GB';
            app.Label_6 = uilabel(app.GridLayout_Options);
            app.Label_6.Text = '容量上限';
            app.Label_8 = uilabel(app.GridLayout_Options);
            app.Label_8.Text = '操作树(深度)';
            app.DropDown_BranchDepth = uidropdown(app.GridLayout_Options);
            app.DropDown_BranchDepth.Items = {'1个动作', '3个动作', '尽可能多', '不保留'};
            app.DropDown_BranchDepth.ItemsData = {'LAST', 'LAST3', 'INFINITY', 'OFF'};
            app.DropDown_BranchDepth.ValueChangedFcn = createCallbackFcn(app, @DropDown_BranchDepthValueChanged, true);
            app.DropDown_BranchDepth.Enable = 'off';
            app.DropDown_BranchDepth.Value = 'LAST';
            app.CPULabel = uilabel(app.GridLayout_Options);
            app.CPULabel.Text = '保留数(CPU)';
            app.Spinner_CPU_Protected = uispinner(app.GridLayout_Options);
            app.Spinner_CPU_Protected.Limits = [0 128];
            app.Spinner_CPU_Protected.RoundFractionalValues = 'on';
            app.Spinner_CPU_Protected.ValueDisplayFormat = '%.0f';
            app.Spinner_CPU_Protected.ValueChangedFcn = createCallbackFcn(app, @Spinner_CPU_ProtectedValueChanged, true);
            app.Spinner_CPU_Protected.Value = 1;
            app.GPULabel = uilabel(app.GridLayout_Options);
            app.GPULabel.Text = '保留数(GPU)';
            app.Spinner_GPU_Protected = uispinner(app.GridLayout_Options);
            app.Spinner_GPU_Protected.Limits = [0 128];
            app.Spinner_GPU_Protected.RoundFractionalValues = 'on';
            app.Spinner_GPU_Protected.ValueDisplayFormat = '%.0f';
            app.Spinner_GPU_Protected.ValueChangedFcn = createCallbackFcn(app, @Spinner_GPU_ProtectedValueChanged, true);
            app.Label_9 = uilabel(app.GridLayout_Options);
            app.Label_9.Text = '风格';
            app.DropDown_Style = uidropdown(app.GridLayout_Options);
            app.DropDown_Style.Items = {'浅色', '深色', '跟随系统'};
            app.DropDown_Style.ItemsData = {'LIGHT', 'DARK', 'FOLLOW'};
            app.DropDown_Style.ValueChangedFcn = createCallbackFcn(app, @DropDown_StyleValueChanged, true);
            app.DropDown_Style.Value = 'LIGHT';
            app.Label_10 = uilabel(app.GridLayout_Options);
            app.Label_10.Text = '消息级别';
            app.DropDown_MessageLevel = uidropdown(app.GridLayout_Options);
            app.DropDown_MessageLevel.Items = {'警告', '消息'};
            app.DropDown_MessageLevel.ItemsData = {'WARNING', 'INFO'};
            app.DropDown_MessageLevel.ValueChangedFcn = createCallbackFcn(app, @DropDown_MessageLevelValueChanged, true);
            app.DropDown_MessageLevel.Value = 'WARNING';
            app.Label_12 = uilabel(app.GridLayout_Options);
            app.Label_12.Text = '自动更新';
            app.DropDown_AutoUpdate = uidropdown(app.GridLayout_Options);
            app.DropDown_AutoUpdate.Items = {'启动时', '实时', '每天', '关闭'};
            app.DropDown_AutoUpdate.ItemsData = {'STARTUP', 'RT', 'EVERYDAY', 'OFF'};
            app.DropDown_AutoUpdate.ValueChangedFcn = createCallbackFcn(app, @DropDown_AutoUpdateValueChanged, true);
            app.DropDown_AutoUpdate.Value = 'STARTUP';
            app.Label_13 = uilabel(app.GridLayout_Options);
            app.Label_13.Text = '更新频道';
            app.DropDown_UpdateChannel = uidropdown(app.GridLayout_Options);
            app.DropDown_UpdateChannel.Items = {'正式版', '预览版', '所有'};
            app.DropDown_UpdateChannel.ItemsData = {'REL', 'PRE', 'ALL'};
            app.DropDown_UpdateChannel.ValueChangedFcn = createCallbackFcn(app, @DropDown_UpdateChannelValueChanged, true);
            app.DropDown_UpdateChannel.Value = 'REL';
            app.Label_15 = uilabel(app.GridLayout_Options);
            app.Label_15.Text = '实验功能';
            app.Switch_ExperimentalFeatures = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_ExperimentalFeatures.Items = {'禁用', '启用'};
            app.Switch_ExperimentalFeatures.ItemsData = {'OFF', 'ON'};
            app.Switch_ExperimentalFeatures.ValueChangedFcn = createCallbackFcn(app, @Switch_ExperimentalFeaturesValueChanged, true);
            app.Switch_ExperimentalFeatures.Enable = 'off';
            app.Switch_ExperimentalFeatures.Value = 'OFF';
            app.Label_16 = uilabel(app.GridLayout_Options);
            app.Label_16.Text = '数据保护';
            app.Switch_DataProtected = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_DataProtected.Items = {'禁用', '启用'};
            app.Switch_DataProtected.ItemsData = {'OFF', 'ON'};
            app.Switch_DataProtected.ValueChangedFcn = createCallbackFcn(app, @Switch_DataProtectedValueChanged, true);
            app.Switch_DataProtected.Enable = 'off';
            app.Switch_DataProtected.Value = 'OFF';
            app.GridLayout11 = uigridlayout(app.GridLayout_Options);
            app.EditField_PyPath = uieditfield(app.GridLayout11, 'text');
            app.EditField_PyPath.Editable = 'off';
            app.Button_SelectPython = uibutton(app.GridLayout11, 'push');
            app.Button_SelectPython.ButtonPushedFcn = createCallbackFcn(app, @Button_SelectPythonPushed, true);
            app.Button_SelectPython.Text = '打开';
            app.PythonLabel = uilabel(app.GridLayout_Options);
            app.PythonLabel.Text = 'Python解释器';
            app.Label_17 = uilabel(app.GridLayout_Options);
            app.Label_17.Text = '语言';
            app.DropDown_Language = uidropdown(app.GridLayout_Options);
            app.DropDown_Language.Items = {'中文', 'English', '跟随系统'};
            app.DropDown_Language.ItemsData = {'CN', 'US', 'FOLLOW'};
            app.DropDown_Language.ValueChangedFcn = createCallbackFcn(app, @DropDown_LanguageValueChanged, true);
            app.DropDown_Language.Value = 'CN';
            app.Label_18 = uilabel(app.GridLayout_Options);
            app.Label_18.Text = '简单统计';
            app.Switch_SimpleStatistics = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_SimpleStatistics.Items = {'禁用', '启用'};
            app.Switch_SimpleStatistics.ItemsData = {'OFF', 'ON'};
            app.Switch_SimpleStatistics.ValueChangedFcn = createCallbackFcn(app, @Switch_SimpleStatisticsValueChanged, true);
            app.Switch_SimpleStatistics.Value = 'ON';
            app.Label_19 = uilabel(app.GridLayout_Options);
            app.Label_19.Text = '自动模板';
            app.Switch_AutoTemplate = uiswitch(app.GridLayout_Options, 'slider');
            app.Switch_AutoTemplate.Items = {'禁用', '启用'};
            app.Switch_AutoTemplate.ItemsData = {'OFF', 'ON'};
            app.Switch_AutoTemplate.ValueChangedFcn = createCallbackFcn(app, @Switch_AutoTemplateValueChanged, true);
            app.Switch_AutoTemplate.Value = 'ON';
            app.Label_20 = uilabel(app.GridLayout_Options);
            app.Label_20.Text = '缓存策略';
            app.DropDown_CachePolicy = uidropdown(app.GridLayout_Options);
            app.DropDown_CachePolicy.Items = {'性能', '资源', '平衡'};
            app.DropDown_CachePolicy.ItemsData = {'PERFORMANCE', 'RESOURCES', 'BALANCE'};
            app.DropDown_CachePolicy.ValueChangedFcn = createCallbackFcn(app, @DropDown_CachePolicyValueChanged, true);
            app.DropDown_CachePolicy.Enable = 'off';
            app.DropDown_CachePolicy.Value = 'PERFORMANCE';
            app.GridLayout10 = uigridlayout(app.GridLayout5);
            app.Button_Apply = uibutton(app.GridLayout10, 'push');
            app.Button_Apply.ButtonPushedFcn = createCallbackFcn(app, @Button_ApplyPushed, true);
            app.Button_Apply.Text = '应用';
            app.Button_OK = uibutton(app.GridLayout10, 'push');
            app.Button_OK.ButtonPushedFcn = createCallbackFcn(app, @Button_OKPushed, true);
            app.Button_OK.Text = '确定';
            app.Button_Cancel = uibutton(app.GridLayout10, 'push');
            app.Button_Cancel.ButtonPushedFcn = createCallbackFcn(app, @Button_CancelPushed, true);
            app.Button_Cancel.Text = '取消';
            app.Tab_TaskNetwork = uitab(app.TabGroup);
            app.Tab_TaskNetwork.Title = '任务网络';
            app.GridLayout2 = uigridlayout(app.Tab_TaskNetwork);
            app.Panel_TaskTable = uipanel(app.GridLayout2);
            app.Panel_TaskTable.Title = '任务表';
            app.GridLayout3 = uigridlayout(app.Panel_TaskTable);
            app.UITable_TaskView = uitable(app.GridLayout3);
            app.UITable_TaskView.ColumnName = {'用户'; '状态'; '进度'; '用时'; '#CPU'; '#GPU'; '内存'; '磁盘'};
            app.UITable_TaskView.RowName = {};
            app.UITable_TaskView.SelectionType = 'row';
            app.Panel_TaskNetworkTopology = uipanel(app.GridLayout2);
            app.Panel_TaskNetworkTopology.Title = '网络拓扑';
            app.GridLayout4 = uigridlayout(app.Panel_TaskNetworkTopology);
            app.UIAxes_TaskNetwork = uiaxes(app.GridLayout4);
            zlabel(app.UIAxes_TaskNetwork, 'Z')
            app.UIAxes_TaskNetwork.XTick = [];
            app.UIAxes_TaskNetwork.YTick = [];
            % Show the figure after all components are created
            app.ResourceManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResourceManager(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ResourceManagerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ResourceManagerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResourceManagerUIFigure)
        end
    end
end