classdef SignalEstimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalExtractorV100UIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        GridLayout12                    matlab.ui.container.GridLayout
        Button_Save                     matlab.ui.control.Button
        Button_Preview                  matlab.ui.control.Button
        Button_Fit                      matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        Tree_Components                 matlab.ui.container.CheckBoxTree
        Node_BKG                        matlab.ui.container.TreeNode
        GridLayout6                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        AutoButton                      matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        Panel_BackgroundModel           matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        CheckBox_FluorescenceOnly       matlab.ui.control.CheckBox
        GridLayout4                     matlab.ui.container.GridLayout
        Button_AutoBackground           matlab.ui.control.StateButton
        EditField_FixedBackground       matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Panel_BaselineModel             matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        Tab_MoveQuantile                matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Button_AutoWindow_MoveQuantile  matlab.ui.control.StateButton
        EditField_Window_MoveQuantile   matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        Button_AutoQuantile_MoveQuantile  matlab.ui.control.StateButton
        EditField_MinValue_MoveQuantile  matlab.ui.control.NumericEditField
        QuantileminLabel                matlab.ui.control.Label
        Tab_MixedExponential            matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        Button_AutoOrder_MixedExponential  matlab.ui.control.StateButton
        Spinner_RegressionOrder_MixedExponential  matlab.ui.control.Spinner
        Label_4                         matlab.ui.control.Label
        Button_AutoQuantile_MixedExponential  matlab.ui.control.StateButton
        EditField_MinValue_MixedExponential  matlab.ui.control.NumericEditField
        QuantileminLabel_2              matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    properties (Access = ?BugReporter, Constant)
        VER = "1.0.0"
    end
    
    properties (Access = private, Hidden)
        caller          % 1-by-1 Register, the parent app
        mask            % m-by-n-by-p label matrix, 0 as background, 1,2,... as valid component
        img_ext         % 1-by-1 regmov object, the extracted image
        sig_channel     % 1-by-1 string, "r","g" or "b", indicate the signal channel
        opt_sig         % 1-by-1 sigopt object, signal extraction options   
        estimator       % 1-by-1 SignalExtractor object
        langmap         % 1-by-1 struct, mapping for language
    end
    
    methods (Access = public)
        
        function RefreshPanelLanguage(app)
            [~, app.langmap] = app.caller.GetLangsInfo();
            
        end

        function Close(app)
            SignalExtractorV100UIFigureCloseRequest(app, []);
        end
    end

    methods (Access = private)

        function InitData(app, mainapp)
            app.caller = mainapp;

            app.mask = app.caller.GetMask();
            app.img_ext = app.caller.GetCurrentSource();
            app.sig_channel = app.caller.GetSignalChannel();
            app.opt_sig = sigopt("MoveMedian", "Constant");
            app.estimator = Estimator(app.img_ext, app.opt_sig);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
        end

        % Close request function: SignalExtractorV100UIFigure
        function SignalExtractorV100UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalExtractorV100UIFigure = uifigure('Visible', 'off');
            app.SignalExtractorV100UIFigure.Name = 'Signal Extractor V1.0.0';
            app.SignalExtractorV100UIFigure.CloseRequestFcn = createCallbackFcn(app, @SignalExtractorV100UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.SignalExtractorV100UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '蒙版组分';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = '模型参数';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.Panel_BaselineModel = uipanel(app.GridLayout6);
            app.Panel_BaselineModel.Title = '基线模型';
            app.GridLayout3 = uigridlayout(app.Panel_BaselineModel);
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.Tab_MoveQuantile = uitab(app.TabGroup);
            app.Tab_MoveQuantile.Title = '移动分位数';
            app.GridLayout7 = uigridlayout(app.Tab_MoveQuantile);
            app.QuantileminLabel = uilabel(app.GridLayout7);
            app.QuantileminLabel.Text = '分位值';
            app.EditField_MinValue_MoveQuantile = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_MinValue_MoveQuantile.Limits = [0 100];
            app.EditField_MinValue_MoveQuantile.RoundFractionalValues = 'on';
            app.EditField_MinValue_MoveQuantile.Value = 8;
            app.Button_AutoQuantile_MoveQuantile = uibutton(app.GridLayout7, 'state');
            app.Button_AutoQuantile_MoveQuantile.Text = 'Auto';
            app.EditField_2Label = uilabel(app.GridLayout7);
            app.EditField_2Label.Text = '窗口';
            app.EditField_Window_MoveQuantile = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_Window_MoveQuantile.Limits = [0 Inf];
            app.EditField_Window_MoveQuantile.RoundFractionalValues = 'on';
            app.EditField_Window_MoveQuantile.Value = 100;
            app.Button_AutoWindow_MoveQuantile = uibutton(app.GridLayout7, 'state');
            app.Button_AutoWindow_MoveQuantile.Text = 'Auto';
            app.Tab_MixedExponential = uitab(app.TabGroup);
            app.Tab_MixedExponential.Title = '混合指数';
            app.GridLayout11 = uigridlayout(app.Tab_MixedExponential);
            app.QuantileminLabel_2 = uilabel(app.GridLayout11);
            app.QuantileminLabel_2.Text = '分位值';
            app.EditField_MinValue_MixedExponential = uieditfield(app.GridLayout11, 'numeric');
            app.EditField_MinValue_MixedExponential.Limits = [0 100];
            app.EditField_MinValue_MixedExponential.RoundFractionalValues = 'on';
            app.EditField_MinValue_MixedExponential.Value = 8;
            app.Button_AutoQuantile_MixedExponential = uibutton(app.GridLayout11, 'state');
            app.Button_AutoQuantile_MixedExponential.Text = 'Auto';
            app.Label_4 = uilabel(app.GridLayout11);
            app.Label_4.Text = '回归阶';
            app.Spinner_RegressionOrder_MixedExponential = uispinner(app.GridLayout11);
            app.Spinner_RegressionOrder_MixedExponential.Limits = [1 5];
            app.Spinner_RegressionOrder_MixedExponential.ValueDisplayFormat = '%.0f';
            app.Spinner_RegressionOrder_MixedExponential.Value = 1;
            app.Button_AutoOrder_MixedExponential = uibutton(app.GridLayout11, 'state');
            app.Button_AutoOrder_MixedExponential.Text = 'Auto';
            app.Panel_BackgroundModel = uipanel(app.GridLayout6);
            app.Panel_BackgroundModel.Title = '背景模型';
            app.GridLayout10 = uigridlayout(app.Panel_BackgroundModel);
            app.GridLayout4 = uigridlayout(app.GridLayout10);
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '固有背景';
            app.EditField_FixedBackground = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_FixedBackground.Limits = [0 65535];
            app.EditField_FixedBackground.RoundFractionalValues = 'on';
            app.EditField_FixedBackground.Value = 100;
            app.Button_AutoBackground = uibutton(app.GridLayout4, 'state');
            app.Button_AutoBackground.Text = 'Auto';
            app.GridLayout5 = uigridlayout(app.GridLayout10);
            app.CheckBox_FluorescenceOnly = uicheckbox(app.GridLayout5);
            app.CheckBox_FluorescenceOnly.Text = '仅荧光强度';
            app.Panel = uipanel(app.GridLayout6);
            app.Panel.Title = '噪声模型';
            app.GridLayout14 = uigridlayout(app.Panel);
            app.DropDown = uidropdown(app.GridLayout14);
            app.DropDown.Items = {'正态分布', '指数分布', '伽马分布', '无'};
            app.DropDown.Value = '正态分布';
            app.AutoButton = uibutton(app.GridLayout14, 'push');
            app.AutoButton.Text = 'Auto';
            app.GridLayout13 = uigridlayout(app.GridLayout2);
            app.Tree_Components = uitree(app.GridLayout13, 'checkbox');
            app.Tree_Components.Editable = 'on';
            app.Node_BKG = uitreenode(app.Tree_Components);
            app.Node_BKG.Text = '0';
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.Button_Fit = uibutton(app.GridLayout12, 'push');
            app.Button_Fit.Text = '拟合';
            app.Button_Preview = uibutton(app.GridLayout12, 'push');
            app.Button_Preview.Text = '预览';
            app.Button_Save = uibutton(app.GridLayout12, 'push');
            app.Button_Save.Text = '保存';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'质心'; '等效半径'; '表面积'; '体积'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.SignalExtractorV100UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalEstimator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SignalExtractorV100UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SignalExtractorV100UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalExtractorV100UIFigure)
        end
    end
end